classdef FarfieldBeam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratebeamfromparaxialfarfieldUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ResettoDefaultsMenu             matlab.ui.container.Menu
        RefreshDropdownsMenu            matlab.ui.container.Menu
        ClearOutputMenu                 matlab.ui.container.Menu
        GenerateCodeMenu                matlab.ui.container.Menu
        ExittoLauncherMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GitHubRepoMenu                  matlab.ui.container.Menu
        OnlineManualMenu                matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        VariableNameEditFieldLabel      matlab.ui.control.Label
        VariableNameEditField           matlab.ui.control.EditField
        GenerateButton                  matlab.ui.control.Button
        WavelengthvacuumEditFieldLabel  matlab.ui.control.Label
        WavelengthvacuumEditField       matlab.ui.control.EditField
        NmaxSpinnerLabel                matlab.ui.control.Label
        NmaxSpinner                     matlab.ui.control.Spinner
        AutoCheckBox                    matlab.ui.control.CheckBox
        IndexmediumEditFieldLabel       matlab.ui.control.Label
        IndexmediumEditField            matlab.ui.control.EditField
        NASpinnerLabel                  matlab.ui.control.Label
        NASpinner                       matlab.ui.control.Spinner
        MappingModeDropDownLabel        matlab.ui.control.Label
        MappingModeDropDown             matlab.ui.control.DropDown
        Gauge                           matlab.ui.control.LinearGauge
        EfarfieldmatrixLabel            matlab.ui.control.Label
        EfarfieldmatrixDropDown         matlab.ui.control.DropDown
        PolarisationEditFieldLabel      matlab.ui.control.Label
        PolarisationEditField           matlab.ui.control.EditField
        WarningNAlargerthanIndexmediumLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function ProgressCallback(app, progress)
            % Update the guage with the progress value
            app.Gauge.Value = 100*progress;
            drawnow;
        end
    
        function ResetToDefaults(app)
            
            % Populate beam and T-matrix drop down lists
            app.RefreshDropDownLists(true);
            
            app.VariableNameEditField.Value = 'Beam';
            app.WavelengthvacuumEditField.Value = '1.0';
            app.NmaxSpinner.Value = 30;
            app.AutoCheckBox.Value = 1;
            app.IndexmediumEditField.Value = '1.0';
            app.NASpinner.Value = 0.9;
            app.MappingModeDropDown.Value = 'sintheta';
            app.PolarisationEditField.Value = '[1, 1i]';
            
            % Clear outputs
            app.ClearOutputs();
            
        end
    
        function RefreshDropDownLists(app, clear_value)
            
            if nargin < 2
                clear_value = false;
            end
            
            % Refresh the drop down menus
            ott.ui.support.populateVariableList(app.EfarfieldmatrixDropDown, 'numeric');
            
            if clear_value
                app.EfarfieldmatrixDropDown.Value = '';
            end
            
        end
    
        function ClearOutputs(app)
            % Clear the output axes
            
            % Rest Gauge
            app.Gauge.Value = 0;
            
            % Clear outputs
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate UI defaults
            app.ResetToDefaults();
            
            % Setup initla state for warnings
            app.WarningNAlargerthanIndexmediumLabel.Visible = 'off';
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Check variable name for output
            beamName = app.VariableNameEditField.Value;
            if ~isvarname(beamName)
                error('Must supply a valid variable name for output');
            end
            
            % Get image data
            if isempty(app.EfarfieldmatrixDropDown.Value)
               error('Must supply image matrix data');
            end
            im = evalin('base', app.EfarfieldmatrixDropDown.Value);
            
            % Get parameters from GUI
            mapping = app.MappingModeDropDown.Value;
            polarisation = evalin('base', app.PolarisationEditField.Value);
            index_medium = evalin('base', app.IndexmediumEditField.Value);
            NA = app.NASpinner.Value;
            wavelength0 = evalin('base', app.WavelengthvacuumEditField.Value);
            
            % Get Nmax value
            if app.AutoCheckBox.Value
                Nmax = 30;
            else
                Nmax = app.NmaxSpinner.Value;
            end
            
            app.ProgressCallback(0);
            
            % Generate beam
            beam = ott.BscPmParaxial(NA, im, ...
                'polarisation', polarisation, ...
                'wavelength0', wavelength0, ...
                'Nmax', Nmax, ...
                'index_medium', index_medium, ...
                'mapping', mapping, ...
                'progress_callback', @(p) app.ProgressCallback(p));
               
            % Update the UI with the latest auto Nmax value
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Value = beam.Nmax;
            end
            
            % Write beam to workspace
            assignin('base', beamName, beam);
            
            % Update the displays
            range = [1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            xrange = linspace(-range(1), range(1), 80)./wavelength0;
            imZ = beam.visualise('axis', 'z', 'range', range);
            imY = beam.visualise('axis', 'y', 'range', range);
            imagesc(app.UIAxes, xrange, xrange, imZ);
            axis(app.UIAxes, 'image');
            imagesc(app.UIAxes_2, xrange, xrange, imY);
            axis(app.UIAxes_2, 'image');
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Enable = 'off';
            else
                app.NmaxSpinner.Enable = 'on';
            end
        end

        % Value changed function: IndexmediumEditField, NASpinner
        function IndexmediumEditFieldValueChanged(app, event)
                        
            % Get index medium
            index_medium = evalin('base', app.IndexmediumEditField.Value);
            
            % Toggle warning about NA above index_medium
            if app.NASpinner.Value > index_medium
                app.WarningNAlargerthanIndexmediumLabel.Visible = 'on';
            else
                app.WarningNAlargerthanIndexmediumLabel.Visible = 'off';
            end
        end

        % Menu selected function: ResettoDefaultsMenu
        function ResettoDefaultsMenuSelected(app, event)
            app.ResetToDefaults();
        end

        % Menu selected function: RefreshDropdownsMenu
        function RefreshDropdownsMenuSelected(app, event)
            app.RefreshDropDownLists();
        end

        % Menu selected function: GenerateCodeMenu
        function GenerateCodeMenuSelected(app, event)
            
            mcode = {};
            
            % Document header
            mcode{end+1} = '% Generate a beam with paraxial point matching';
            mcode{end+1} = '% Automatically generated using the Optical Tweezers Toolbox';
            mcode{end+1} = '%';
            mcode{end+1} = '% Required variables: im (a NxN or NxNx2 matrix)';
            mcode{end+1} = '';
            
            % Get parameters from GUI
            mcode{end+1} = ['mapping = ''', app.MappingModeDropDown.Value, ''';'];
            mcode{end+1} = ['polarisation = ', app.PolarisationEditField.Value, ';'];
            mcode{end+1} = ['index_medium = ', app.IndexmediumEditField.Value, ';'];
            mcode{end+1} = ['NA = ', num2str(app.NASpinner.Value), ';'];
            mcode{end+1} = ['wavelength0 = ', app.WavelengthvacuumEditField.Value, ';'];
            
            % Get Nmax value
            if app.AutoCheckBox.Value
                mcode{end+1} = ['Nmax = ', num2str(30), ';'];
            else
                mcode{end+1} = ['Nmax = ', num2str(app.NmaxSpinner.Value), ';'];
            end
            mcode{end+1} = '';
            
            % Generate beam
            mcode{end+1} = 'beam = ott.BscPmParaxial(NA, im, ...';
            mcode{end+1} = '    ''polarisation'', polarisation, ...';
            mcode{end+1} = '    ''wavelength0'', wavelength0, ...';
            mcode{end+1} = '    ''Nmax'', Nmax, ...';
            mcode{end+1} = '    ''index_medium'', index_medium, ...';
            mcode{end+1} = '    ''mapping'', mapping);';
            
            % Convert to char array, add line endings
            mcode_str = [];
            for ii = 1:length(mcode)
                mcode_str = [mcode_str, mcode{ii}, newline]; %#ok<AGROW>
            end
            
            % Create new document
            editorDoc = matlab.desktop.editor.newDocument(mcode_str); 
            editorDoc.smartIndentContents();
            editorDoc.goToLine(1);
            
        end

        % Menu selected function: ExittoLauncherMenu
        function ExittoLauncherMenuSelected(app, event)
            ott.ui.support.ShowUiLauncher();
            delete(app);
        end

        % Menu selected function: GitHubRepoMenu
        function GitHubRepoMenuSelected(app, event)
            web('https://github.com/ilent2/ott', '-browser');
        end

        % Menu selected function: OnlineManualMenu
        function OnlineManualMenuSelected(app, event)
            web('https://ott.readthedocs.io/en/latest/', '-browser');
        end

        % Menu selected function: ReportaBugMenu
        function ReportaBugMenuSelected(app, event)
            web('https://github.com/ilent2/ott/issues', '-browser');
        end

        % Menu selected function: ClearOutputMenu
        function ClearOutputMenuSelected(app, event)
            app.ClearOutputs();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratebeamfromparaxialfarfieldUIFigure = uifigure;
            app.GeneratebeamfromparaxialfarfieldUIFigure.Name = 'Generate beam from paraxial far-field';
            app.FileMenu = uimenu(app.GeneratebeamfromparaxialfarfieldUIFigure);
            app.FileMenu.Text = 'File';
            app.ResettoDefaultsMenu = uimenu(app.FileMenu);
            app.ResettoDefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultsMenuSelected, true);
            app.ResettoDefaultsMenu.Text = 'Reset to Defaults';
            app.RefreshDropdownsMenu = uimenu(app.FileMenu);
            app.RefreshDropdownsMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshDropdownsMenuSelected, true);
            app.RefreshDropdownsMenu.Text = 'Refresh Dropdowns';
            app.ClearOutputMenu = uimenu(app.FileMenu);
            app.ClearOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearOutputMenuSelected, true);
            app.ClearOutputMenu.Text = 'Clear Output';
            app.GenerateCodeMenu = uimenu(app.FileMenu);
            app.GenerateCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCodeMenuSelected, true);
            app.GenerateCodeMenu.Separator = 'on';
            app.GenerateCodeMenu.Text = 'Generate Code...';
            app.ExittoLauncherMenu = uimenu(app.FileMenu);
            app.ExittoLauncherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExittoLauncherMenuSelected, true);
            app.ExittoLauncherMenu.Separator = 'on';
            app.ExittoLauncherMenu.Text = 'Exit to Launcher';
            app.HelpMenu = uimenu(app.GeneratebeamfromparaxialfarfieldUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubRepoMenu = uimenu(app.HelpMenu);
            app.GitHubRepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubRepoMenuSelected, true);
            app.GitHubRepoMenu.Text = 'GitHub Repo';
            app.OnlineManualMenu = uimenu(app.HelpMenu);
            app.OnlineManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineManualMenuSelected, true);
            app.OnlineManualMenu.Text = 'Online Manual';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportaBugMenuSelected, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.Panel = uipanel(app.GeneratebeamfromparaxialfarfieldUIFigure);
            app.VariableNameEditFieldLabel = uilabel(app.Panel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.Panel, 'text');
            app.VariableNameEditField.Value = 'Beam';
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.WavelengthvacuumEditFieldLabel = uilabel(app.Panel);
            app.WavelengthvacuumEditFieldLabel.Text = 'Wavelength (vacuum)';
            app.WavelengthvacuumEditField = uieditfield(app.Panel, 'text');
            app.WavelengthvacuumEditField.Value = '1.0';
            app.NmaxSpinnerLabel = uilabel(app.Panel);
            app.NmaxSpinnerLabel.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.Panel);
            app.NmaxSpinner.Limits = [1 Inf];
            app.NmaxSpinner.RoundFractionalValues = 'on';
            app.NmaxSpinner.Enable = 'off';
            app.NmaxSpinner.Value = 30;
            app.AutoCheckBox = uicheckbox(app.Panel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.IndexmediumEditFieldLabel = uilabel(app.Panel);
            app.IndexmediumEditFieldLabel.Text = 'Index (medium)';
            app.IndexmediumEditField = uieditfield(app.Panel, 'text');
            app.IndexmediumEditField.ValueChangedFcn = createCallbackFcn(app, @IndexmediumEditFieldValueChanged, true);
            app.IndexmediumEditField.Value = '1.0';
            app.NASpinnerLabel = uilabel(app.Panel);
            app.NASpinnerLabel.Text = 'NA';
            app.NASpinner = uispinner(app.Panel);
            app.NASpinner.Step = 0.1;
            app.NASpinner.LowerLimitInclusive = 'off';
            app.NASpinner.Limits = [0 Inf];
            app.NASpinner.ValueChangedFcn = createCallbackFcn(app, @IndexmediumEditFieldValueChanged, true);
            app.NASpinner.Value = 0.9;
            app.MappingModeDropDownLabel = uilabel(app.Panel);
            app.MappingModeDropDownLabel.Text = 'Mapping Mode';
            app.MappingModeDropDown = uidropdown(app.Panel);
            app.MappingModeDropDown.Items = {'sin(theta)', 'tan(theta)', 'theta'};
            app.MappingModeDropDown.ItemsData = {'sintheta', 'tantheta', 'theta'};
            app.MappingModeDropDown.Value = 'sintheta';
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.EfarfieldmatrixLabel = uilabel(app.Panel);
            app.EfarfieldmatrixLabel.Text = 'E far-field (matrix)';
            app.EfarfieldmatrixDropDown = uidropdown(app.Panel);
            app.EfarfieldmatrixDropDown.Items = {};
            app.EfarfieldmatrixDropDown.Editable = 'on';
            app.EfarfieldmatrixDropDown.Value = {};
            app.PolarisationEditFieldLabel = uilabel(app.Panel);
            app.PolarisationEditFieldLabel.Text = 'Polarisation';
            app.PolarisationEditField = uieditfield(app.Panel, 'text');
            app.PolarisationEditField.Value = '[1, 1i]';
            app.WarningNAlargerthanIndexmediumLabel = uilabel(app.Panel);
            app.WarningNAlargerthanIndexmediumLabel.Text = 'Warning: NA larger than Index (medium)';
            app.UIAxes = uiaxes(app.GeneratebeamfromparaxialfarfieldUIFigure);
            xlabel(app.UIAxes, 'X Position [\lambda_0]')
            ylabel(app.UIAxes, 'Y Position [\lambda_0]')
            app.UIAxes_2 = uiaxes(app.GeneratebeamfromparaxialfarfieldUIFigure);
            xlabel(app.UIAxes_2, 'Z Position [\lambda_0]')
            ylabel(app.UIAxes_2, 'X Position [\lambda_0]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = FarfieldBeam

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratebeamfromparaxialfarfieldUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratebeamfromparaxialfarfieldUIFigure)
        end
    end
end