classdef Visualise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizebeamUIFigure          matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ResettoDefaultsMenu            matlab.ui.container.Menu
        RefreshDropdownsMenu           matlab.ui.container.Menu
        GenerateCodeMenu               matlab.ui.container.Menu
        ExittoLauncherMenu             matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        GitHubRepoMenu                 matlab.ui.container.Menu
        OnlineManualMenu               matlab.ui.container.Menu
        ReportaBugMenu                 matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        FarfieldimageTab               matlab.ui.container.Tab
        DirectionDropDownLabel         matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        CustomDirectionEditFieldLabel  matlab.ui.control.Label
        CustomDirectionEditField       matlab.ui.control.EditField
        MappingModeDropDownLabel       matlab.ui.control.Label
        MappingModeDropDown            matlab.ui.control.DropDown
        FFYResolutionSpinner           matlab.ui.control.Spinner
        ResolutionSpinner_3Label       matlab.ui.control.Label
        FFXResolutionSpinner           matlab.ui.control.Spinner
        FarfieldsurfaceTab             matlab.ui.container.Tab
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        NormaliseCheckBox              matlab.ui.control.CheckBox
        AxesEditFieldLabel             matlab.ui.control.Label
        AxesEditField                  matlab.ui.control.EditField
        ScaleLabel                     matlab.ui.control.Label
        ResolutionSpinnerLabel_2       matlab.ui.control.Label
        FFXResolutionSpinner_2         matlab.ui.control.Spinner
        NearfieldsliceTab              matlab.ui.container.Tab
        OffsetSpinnerLabel             matlab.ui.control.Label
        OffsetSpinner                  matlab.ui.control.Spinner
        WidthSpinnerLabel              matlab.ui.control.Label
        WidthSpinner                   matlab.ui.control.Spinner
        HeightSpinner                  matlab.ui.control.Spinner
        ResolutionSpinnerLabel         matlab.ui.control.Label
        NFXResolutionSpinner           matlab.ui.control.Spinner
        NFYResolutionSpinner           matlab.ui.control.Spinner
        AxisDropDownLabel              matlab.ui.control.Label
        AxisDropDown                   matlab.ui.control.DropDown
        CustomAxesEditFieldLabel       matlab.ui.control.Label
        CustomAxesEditField            matlab.ui.control.EditField
        AutoWidthCheckBox              matlab.ui.control.CheckBox
        Nearfield3DcontourTab          matlab.ui.container.Tab
        LogspacingCheckBox             matlab.ui.control.CheckBox
        ContoursEditFieldLabel         matlab.ui.control.Label
        ContoursEditField              matlab.ui.control.EditField
        Nf3dHeightSpinner              matlab.ui.control.Spinner
        Nf3dYResolutionSpinner         matlab.ui.control.Spinner
        WidthSpinner_2Label            matlab.ui.control.Label
        Nf3dWidthSpinner               matlab.ui.control.Spinner
        ResolutionSpinner_2Label       matlab.ui.control.Label
        Nf3dXResolutionSpinner         matlab.ui.control.Spinner
        Nf3dDepthSpinner               matlab.ui.control.Spinner
        Nf3dZResolutionSpinner         matlab.ui.control.Spinner
        OriginEditFieldLabel           matlab.ui.control.Label
        OriginEditField                matlab.ui.control.EditField
        AutoWidthCheckBox_2            matlab.ui.control.CheckBox
        TextArea                       matlab.ui.control.TextArea
        GenerateButton                 matlab.ui.control.Button
        ReusefigureCheckBox            matlab.ui.control.CheckBox
        AutogenerateCheckBox           matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        BasisDropDownLabel             matlab.ui.control.Label
        BasisDropDown                  matlab.ui.control.DropDown
        VisualiseDropDownLabel         matlab.ui.control.Label
        VisualiseDropDown              matlab.ui.control.DropDown
        FieldTypeDropDownLabel         matlab.ui.control.Label
        FieldTypeDropDown              matlab.ui.control.DropDown
        IncidentBeamDropDownLabel      matlab.ui.control.Label
        IncidentBeamDropDown           matlab.ui.control.DropDown
        ScatteredBeamDropDownLabel     matlab.ui.control.Label
        ScatteredBeamDropDown          matlab.ui.control.DropDown
        Gauge                          matlab.ui.control.LinearGauge
    end


    properties (Access = public)
        LastFigure % Handle to the last figure generated
    end

    methods (Access = private)
    
        function VisualiseFarfieldImage(app)
            % Generate the visualisation of the far-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            switch app.DirectionDropDown.Value
                case '+X'
                    direction = [pi/2, 0];
                    labels = struct('x', 'Z', 'y', 'Y');
                case '-X'
                    direction = [-pi/2, 0];
                    labels = struct('x', 'Z', 'y', 'Y');
                case '+Y'
                    direction = [pi/2, pi/2];
                    labels = struct('x', 'Z', 'y', 'X');
                case '-Y'
                    direction = [pi/2, -pi/2];
                    labels = struct('x', 'Z', 'y', 'X');
                case '+Z'
                    direction = 'pos';
                    labels = struct('x', 'X', 'y', 'Y');
                case '-Z'
                    direction = 'neg';
                    labels = struct('x', 'X', 'y', 'Y');
                case 'Custom'
                    direction = evalin('base', app.CustomDirectionEditField.Value);
                    labels = struct('x', '', 'y', '');
                otherwise
                    error('OTT: Internal error: unknown direction drop down value');
            end
            
            beam.visualiseFarfield('direction', direction, ...
                'mapping', app.MappingModeDropDown.Value, ...
                'field', app.FieldTypeDropDown.Value, ...
                'size', [app.FFXResolutionSpinner.Value, app.FFYResolutionSpinner.Value]);
            xlabel(app.LastFigure.CurrentAxes, labels.x);
            ylabel(app.LastFigure.CurrentAxes, labels.y);
            colorbar(app.LastFigure.CurrentAxes);
        end
    
        function VisualiseFarfieldSurface(app)
            % Generate the visualisation of the far-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            % Generate the visualisation
            switch app.TypeDropDown.Value
                case '2dpolar'
%                     beam.visualiseFarfieldSlice();
                case '3dpolar'
                    beam.visualiseFarfieldSphere('npts', app.FFXResolutionSpinner_2.Value, ...
                        'normalise', app.NormaliseCheckBox.Value, ...
                        'type', '3dpolar', ...
                        'field', app.FieldTypeDropDown.Value);
                case 'sphere'
                    beam.visualiseFarfieldSphere('npts', app.FFXResolutionSpinner_2.Value, ...
                        'normalise', app.NormaliseCheckBox.Value, ...
                        'type', 'sphere', ...
                        'field', app.FieldTypeDropDown.Value);
                otherwise
                    error('OTT: Internal error: Unknown type drop down value');
            end
        end
    
        function VisualiseNearfieldImage(app)
            % Generate the visualisation of the near-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            switch app.AxisDropDown.Value
                case 'X-Y'
                    direction = {[1, 0, 0], [0, 1, 0]};
                    labels = struct('x', 'X [\lambda]', 'y', 'Y [\lambda]');
                case 'X-Z'
                    direction = {[0, 0, 1], [1, 0, 0]};
                    labels = struct('x', 'Z [\lambda]', 'y', 'X [\lambda]');
                case 'Y-Z'
                    direction = {[0, 0, 1], [0, 1, 0]};
                    labels = struct('x', 'Z [\lambda]', 'y', 'Y [\lambda]');
                case 'Custom'
                    direction = evalin('base', app.CustomAxesEditField.Value);
                    labels = struct('x', 'direction 1', 'y', 'direction 2');
                otherwise
                    error('OTT: Internal error: unknown axes drop down value');
            end
            
            % Handle width
            if app.AutoWidthCheckBox.Value
                range = [1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            else
                range = [app.WidthSpinner.Value, app.HeightSpinner.Value];
            end
            
            % Generate the figure
            beam.visualise('axis', direction, ...
                'field', app.FieldTypeDropDown.Value, ...
                'size', [app.NFXResolutionSpinner.Value, app.NFYResolutionSpinner.Value], ...
                'offset', app.OffsetSpinner.Value, ...
                'range', range);
            xlabel(app.LastFigure.CurrentAxes, labels.x);
            ylabel(app.LastFigure.CurrentAxes, labels.y);
            colorbar(app.LastFigure.CurrentAxes);
            
        end
    
        function Visualise3dContours(app)
            % Generate visualisation of 3-D near field with contours
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            % Translate the beam
            position = evalin('base', app.OriginEditField.Value);
            assert(isnumeric(position) && numel(position) == 3, 'Position must be 3 element vector');
            beam = beam.translateXyz(position(:));
            
            % Handle width
            if app.AutoWidthCheckBox.Value
                range = [1,1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            else
                range = [app.Nf3dWidthSpinner.Value, app.Nf3dHeightSpinner.Value, app.Nf3dDepthSpinner.Value];
            end
            
            % Get resultion
            resolution = [app.Nf3dXResolutionSpinner.Value, app.Nf3dYResolutionSpinner.Value, app.Nf3dZResolutionSpinner.Value];
            
            x = linspace(-range(1), range(1), resolution(1));
            y = linspace(-range(2), range(2), resolution(2));
            z = linspace(-range(3), range(3), resolution(3));
            
            [xx, yy, zz] = ndgrid(x, y, z);
            xyz = [xx(:), yy(:), zz(:)];
            
            % Calculate the field
            E = beam.emFieldXyz(xyz.');
            
            % Convert from E to desired field
            im = beam.GetVisualisationData(app.FieldTypeDropDown.Value, xyz, [], E.', []);
            
            % Reshape the output
            im = reshape(im, resolution);
            
            % Calculate contour plot levels
            levels = evalin('base', ['[', app.ContoursEditField.Value, ']']);
            if isempty(levels)
                error('Must supply at least one element in levels');
            elseif numel(levels) == 1
                
                minim = min(im(:));
                maxim = max(im(:));
                
                % Equally spaced levels
                if app.LogspacingCheckBox.Value
                    if minim < 0
                        error('Log-scale needs positive valued field');
                    end
                    levels = logspace(log10(minim), log10(maxim), levels(1));
                    disp(levels);
                else
                    levels = linspace(minim, maxim, levels(1));
                end
            elseif numel(levels) == 2 && levels(1) == levels(2)
                levels = levels(1);
            end
            
            cla(app.LastFigure.CurrentAxes);
            disp('Starting contour calculation...');

            cmap = colormap(app.LastFigure.CurrentAxes);

            for ii = 1:length(levels)
                
                % Calculate the color for this contour
                scaled_level = (levels(ii) - min(levels))./(max(levels)-min(levels));
                if isnan(scaled_level)
                    scaled_level = 0.5;
                end
                color = interp1(linspace(0, 1, size(cmap, 1)), cmap, scaled_level);
                
                % Generate the surface
                p = patch(app.LastFigure.CurrentAxes, isosurface(im, levels(ii)), 'EdgeColor', 'none', 'FaceColor', color);
                isonormals(im,p);
                
                hold(app.LastFigure.CurrentAxes, 'on');
            end
            camlight(app.LastFigure.CurrentAxes);
            lighting(app.LastFigure.CurrentAxes, 'phong');
            axis(app.LastFigure.CurrentAxes, 'equal');
            view(app.LastFigure.CurrentAxes, [20, 20]);
            
            disp('done contour calculation');
            
            hold(app.LastFigure.CurrentAxes, 'off');
        end
        
        function beam = GetBeam(app)
            
            % Get incident beam
            if ~isempty(app.IncidentBeamDropDown.Value)
                ibeam = evalin('base', app.IncidentBeamDropDown.Value);
            else
                ibeam = [];
            end
            
            % Get scattered beam
            if ~isempty(app.ScatteredBeamDropDown.Value)
                sbeam = evalin('base', app.ScatteredBeamDropDown.Value);
            else
                sbeam = [];
            end
            
            % Get the scattered, total or incident beam
            switch app.VisualiseDropDown.Value
                case 'incident'
                    if isempty(ibeam)
                        error('Incident beam must be supplied');
                    end
                    beam = ibeam;
                case 'scattered'
                    if isempty(sbeam)
                        error('Scattered beam must be supplied');
                    end
                    if strcmpi(sbeam.type, 'total') && isempty(ibeam)
                        error('Can''t display scattered field, need incident beam');
                    end
                    beam = sbeam.scatteredField(ibeam);
                case 'total'
                    if isempty(sbeam)
                        error('Scattered beam must be supplied');
                    end
                    if strcmpi(sbeam.type, 'scattered') && isempty(ibeam)
                        error('Can''t display total field, need incident beam');
                    end
                    beam = sbeam.totalField(ibeam);
                otherwise
                    error(['OTT: Internal error: invalid visualise drop down value: ' app.VisualiseDropDown.Value]);
            end
            
            % Set the basis
            beam.basis = app.BasisDropDown.Value;
        end
    
        function ResetToDefaults(app)
            
            % Populate beam and T-matrix drop down lists
            app.RefreshDropDownLists(true);
            
            app.TabGroup.SelectedTab = app.FarfieldimageTab;
            
            % Far-field image tab
            app.DirectionDropDown.Value = '+Z';
            app.CustomDirectionEditField.Value = '[0, 0]';
            app.MappingModeDropDown.Value = 'sin';
            app.FFYResolutionSpinner.Value = 80;
            app.FFXResolutionSpinner.Value = 80;
            
            % Farfield surface tab
            app.TypeDropDown.Value = '3dpolar';
            app.NormaliseCheckBox.Value = 0;
            app.AxesEditField.Value = '[1, 0, 0; 0, 1, 0]';
            app.FFXResolutionSpinner_2.Value = 80;
            
            % Near-field slice tab
            app.OffsetSpinner.Value = 0;
            app.WidthSpinner.Value = 1;
            app.HeightSpinner.Value = 1;
            app.NFXResolutionSpinner.Value = 80;
            app.NFYResolutionSpinner.Value = 80;
            app.AxisDropDown.Value = 'X-Y';
            app.CustomAxesEditField.Value = '{[1, 0, 0], [0, 1, 0]}';
            app.AutoWidthCheckBox.Value = 1;
            
            % Near-field 3-D contour tab
            app.LogspacingCheckBox.Value = 0;
            app.ContoursEditField.Value = '1';
            app.Nf3dHeightSpinner.Value = 1;
            app.Nf3dYResolutionSpinner.Value = 40;
            app.Nf3dWidthSpinner.Value = 1;
            app.Nf3dXResolutionSpinner.Value = 40;
            app.Nf3dDepthSpinner.Value = 1;
            app.Nf3dZResolutionSpinner.Value = 40;
            app.OriginEditField.Value = '[0,0,0]';
            app.AutoWidthCheckBox_2.Value = 1;
            
            app.ReusefigureCheckBox.Value = 1;
            app.AutogenerateCheckBox.Value = 0;
            
            app.BasisDropDown.Value = 'incoming';
            app.VisualiseDropDown.Value = 'incident';
            app.FieldTypeDropDown.Value = 'irradiance';
            
        end
    
        function RefreshDropDownLists(app, clear_value)
            
            if nargin < 2
                clear_value = false;
            end
            
            % Refresh the drop down menus
            ott.ui.support.populateVariableList(app.IncidentBeamDropDown, 'ott.Bsc');
            ott.ui.support.populateVariableList(app.ScatteredBeamDropDown, 'ott.Bsc');
            
            if clear_value
                app.IncidentBeamDropDown.Value = '';
                app.ScatteredBeamDropDown.Value = '';
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate UI defaults
            app.ResetToDefaults();
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Open a new figure for the image
            if isempty(app.LastFigure)
                app.LastFigure = figure();
            elseif ~ishandle(app.LastFigure) || ~app.ReusefigureCheckBox.Value
                app.LastFigure = figure();
            else
                figure(app.LastFigure);
            end
            
            % Rest the Gauge
            app.Gauge.Value = 0;
            app.Gauge.BackgroundColor = [1,1,1];
            drawnow;
            
            try
                    
                switch app.TabGroup.SelectedTab.Title
                    case 'Far-field image'
                        app.VisualiseFarfieldImage();
                    case 'Far-field surface'
                        app.VisualiseFarfieldSurface();
                    case 'Near-field slice'
                        app.VisualiseNearfieldImage();
                    case 'Near-field 3-D contour'
                        app.Visualise3dContours();
                    otherwise
                        error('OTT: Internal error: Unknown tab title');
                end
            catch ME
                
                % Show a graphical queue for an error
                app.Gauge.BackgroundColor = [1, 0, 0];
                
                % Continue the error
                rethrow(ME);
            end
            
            % Set the Guage to done
            app.Gauge.Value = 100;
            drawnow;
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            value = app.DirectionDropDown.Value;
            
            if strcmpi(value, 'Custom')
                app.CustomDirectionEditField.Editable = 'on';
            else
                app.CustomDirectionEditField.Editable = 'off';
                switch app.DirectionDropDown.Value
                    case '+X'
                        direction = [pi/2, 0];
                    case '-X'
                        direction = [-pi/2, 0];
                    case '+Y'
                        direction = [pi/2, pi/2];
                    case '-Y'
                        direction = [pi/2, -pi/2];
                    case '+Z'
                        direction = [0, 0];
                    case '-Z'
                        direction = [pi, 0];
                    case 'Custom'
                        direction = evalin('base', app.CustomDirectionEditField.Value);
                    otherwise
                        error('OTT: Internal error: unknown direction drop down value');
                end
                app.CustomDirectionEditField.Value = ['[', num2str(direction(1)), ', ' num2str(direction(2)) ']'];
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
                
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            switch app.TabGroup.SelectedTab.Title
                case 'Far-field image'
                    if strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'incoming';
                    end
                case 'Far-field surface'
                    if strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'incoming';
                    end
                case 'Near-field slice'
                    if ~strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'regular';
                    end
                case 'Near-field 3-D contour'
                    if ~strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'regular';
                    end
                otherwise
                    error('OTT: Internal error: Unknown tab title');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end

        % Value changed function: AxesEditField, BasisDropDown, 
        % CustomAxesEditField, CustomDirectionEditField, 
        % FFXResolutionSpinner, FFXResolutionSpinner_2, 
        % FFYResolutionSpinner, FieldTypeDropDown, HeightSpinner, 
        % IncidentBeamDropDown, MappingModeDropDown, 
        % NFXResolutionSpinner, NFYResolutionSpinner, NormaliseCheckBox, 
        % OffsetSpinner, ScatteredBeamDropDown, VisualiseDropDown, 
        % WidthSpinner
        function AnyValueChanged(app, event)
            
            % Generate the image
            if app.AutogenerateCheckBox.Value
                app.GenerateButtonPushed(event);
            end
            
        end

        % Value changed function: AutogenerateCheckBox
        function AutogenerateCheckBoxValueChanged(app, event)
            
            % Auto-generate requires re-use figure to be checked
            if app.AutogenerateCheckBox.Value
                app.ReusefigureCheckBox.Value = true;
                app.ReusefigureCheckBox.Enable = 'off';
            else
                app.ReusefigureCheckBox.Enable = 'on';
            end
            
            % And a value changed, so update
            app.AnyValueChanged(event);
            
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)

            switch app.AxisDropDown.Value
                case 'X-Y'
                    app.CustomAxesEditField.Value = '{[1, 0, 0], [0, 1, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'X-Z'
                    app.CustomAxesEditField.Value = '{[0, 0, 1], [1, 0, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'Y-Z'
                    app.CustomAxesEditField.Value = '{[0, 0, 1], [0, 1, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'Custom'
                    app.CustomAxesEditField.Editable = 'on';
                otherwise
                    error('OTT: Internal error: wrong AxisDropDown value');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
            
        end

        % Value changed function: AutoWidthCheckBox
        function AutoWidthCheckBoxValueChanged(app, event)

            % Enable/disable width entry
            if app.AutoWidthCheckBox.Value
                app.WidthSpinner.Enable = 'off';
                app.HeightSpinner.Enable = 'off';
            else
                app.WidthSpinner.Enable = 'on';
                app.HeightSpinner.Enable = 'on';
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            switch app.TypeDropDown.Value
                case '2dpolar'
                    app.AxesEditField.Enable = 'on';
                case '3dpolar'
                    app.AxesEditField.Enable = 'off';
                case 'sphere'
                    app.AxesEditField.Enable = 'off';
                otherwise
                    error('OTT: Internal error: unknown type drop down value');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end

        % Menu selected function: ResettoDefaultsMenu
        function ResettoDefaultsMenuSelected(app, event)
            app.ResetToDefaults();
        end

        % Menu selected function: RefreshDropdownsMenu
        function RefreshDropdownsMenuSelected(app, event)
            app.RefreshDropDownLists();
        end

        % Menu selected function: GenerateCodeMenu
        function GenerateCodeMenuSelected(app, event)
            
            mcode = {};
            
            if strcmpi(app.VisualiseDropDown.Value, {'scattered', 'total'})
                sbeam_required = true;
                ibeam_required = true;
            else
                sbeam_required = false;
                ibeam_required = true;
            end
            
            % Document header
            mcode{end+1} = '% Generate a beam visualisation';
            mcode{end+1} = '% Automatically generated using the Optical Tweezers Toolbox';
            mcode{end+1} = '%';
            mcode{end+1} = '% Required variables:';
            if ibeam_required
                mcode{end+1} = '%     ibeam  (incident beam)';
            end
            if sbeam_required
                mcode{end+1} = '%     sbeam  (scattered beam)';
            end
            mcode{end+1} = '';
            
            % Get the scattered, total or incident beam
            mcode{end+1} = '% Get the beam and set the appropriate basis';
            switch app.VisualiseDropDown.Value
                case 'incident'
                    mcode{end+1} = 'vbeam = ibeam;';
                case 'scattered'
                    mcode{end+1} = 'vbeam = sbeam.scatteredField(ibeam);';
                case 'total'
                    mcode{end+1} = 'vbeam = sbeam.totalField(ibeam);';
                otherwise
                    error(['OTT: Internal error: invalid visualise drop down value: ' app.VisualiseDropDown.Value]);
            end
            mcode{end+1} = ['vbeam.basis = ''',  app.BasisDropDown.Value, ''';'];
            mcode{end+1} = '';
            
            mcode{end+1} = 'figure();';
            
            switch app.TabGroup.SelectedTab.Title
                case 'Far-field image'
                    
                    switch app.DirectionDropDown.Value
                        case '+X'
                            mcode{end+1} = 'direction = [pi/2, 0];';
                            mcode{end+1} = 'labels = struct(''x'', ''Z'', ''y'', ''Y'');';
                        case '-X'
                            mcode{end+1} = 'direction = [-pi/2, 0];';
                            mcode{end+1} = 'labels = struct(''x'', ''Z'', ''y'', ''Y'');';
                        case '+Y'
                            mcode{end+1} = 'direction = [pi/2, pi/2];';
                            mcode{end+1} = 'labels = struct(''x'', ''Z'', ''y'', ''X'');';
                        case '-Y'
                            mcode{end+1} = 'direction = [pi/2, -pi/2];';
                            mcode{end+1} = 'labels = struct(''x'', ''Z'', ''y'', ''X'');';
                        case '+Z'
                            mcode{end+1} = 'direction = ''pos'';';
                            mcode{end+1} = 'labels = struct(''x'', ''X'', ''y'', ''Y'');';
                        case '-Z'
                            mcode{end+1} = 'direction = ''neg'';';
                            mcode{end+1} = 'labels = struct(''x'', ''X'', ''y'', ''Y'');';
                        case 'Custom'
                            mcode{end+1} = ['direction = ', app.CustomDirectionEditField.Value, ';'];
                            mcode{end+1} = 'labels = struct(''x'', '''', ''y'', '''');';
                        otherwise
                            error('OTT: Internal error: unknown direction drop down value');
                    end
                    mcode{end+1} = '';
                    
                    mcode{end+1} = 'vbeam.visualiseFarfield(''direction'', direction, ...';
                    mcode{end+1} = ['    ''mapping'', ''', app.MappingModeDropDown.Value, ''', ...'];
                    mcode{end+1} = ['    ''field'', ''', app.FieldTypeDropDown.Value,  ''', ...'];
                    mcode{end+1} = ['    ''size'', [', num2str(app.FFXResolutionSpinner.Value), ', ', num2str(app.FFYResolutionSpinner.Value), ']);'];
                    mcode{end+1} = 'xlabel(labels.x);';
                    mcode{end+1} = 'ylabel(labels.y);';
                    mcode{end+1} = 'colorbar();';
                    
                case 'Far-field surface'
                    
                    % Generate the visualisation
                    mcode{end+1} = ['vbeam.visualiseFarfieldSphere(''npts'', ', num2str(app.FFXResolutionSpinner_2.Value), ', ...'];
                    mcode{end+1} = ['    ''normalise'', ', num2str(app.NormaliseCheckBox.Value), ', ...'];
                    mcode{end+1} = ['    ''field'', ''', app.FieldTypeDropDown.Value, ''', ...'];
                    switch app.TypeDropDown.Value
                        case '3dpolar'
                            mcode{end+1} = '    ''type'', ''3dpolar'');';
                        case 'sphere'
                            mcode{end+1} = '    ''type'', ''sphere'');';
                        otherwise
                            error('OTT: Internal error: Unknown type drop down value');
                    end
                    
                case 'Near-field slice'
                    
                    switch app.AxisDropDown.Value
                        case 'X-Y'
                            mcode{end+1} = 'direction = {[1, 0, 0], [0, 1, 0]};';
                            mcode{end+1} = 'labels = struct(''x'', ''X [\lambda]'', ''y'', ''Y [\lambda]'');';
                        case 'X-Z'
                            mcode{end+1} = 'direction = {[0, 0, 1], [1, 0, 0]};';
                            mcode{end+1} = 'labels = struct(''x'', ''Z [\lambda]'', ''y'', ''X [\lambda]'');';
                        case 'Y-Z'
                            mcode{end+1} = 'direction = {[0, 0, 1], [0, 1, 0]};';
                            mcode{end+1} = 'labels = struct(''x'', ''Z [\lambda]'', ''y'', ''Y [\lambda]'');';
                        case 'Custom'
                            mcode{end+1} = ['direction = ', app.CustomAxesEditField.Value, ';'];
                            mcode{end+1} = 'labels = struct(''x'', ''direction 1'', ''y'', ''direction 2'');';
                        otherwise
                            error('OTT: Internal error: unknown axes drop down value');
                    end
            
                    % Handle width
                    if app.AutoWidthCheckBox.Value
                        mcode{end+1} = 'range = [1,1]*ott.utils.nmax2ka(vbeam.Nmax)/abs(vbeam.k_medium);';
                    else
                        mcode{end+1} = ['range = [', num2str(app.WidthSpinner.Value), ', ', num2str(app.HeightSpinner.Value), '];'];
                    end
                    mcode{end+1} = '';
                    
                    % Generate the figure
                    mcode{end+1} = 'vbeam.visualise(''axis'', direction, ...';
                    mcode{end+1} = ['    ''field'', ''', app.FieldTypeDropDown.Value, ''', ...'];
                    mcode{end+1} = ['    ''size'', [', num2str(app.NFXResolutionSpinner.Value), ', ', num2str(app.NFYResolutionSpinner.Value), '], ...'];
                    mcode{end+1} = ['    ''offset'', ', num2str(app.OffsetSpinner.Value), ', ...'];
                    mcode{end+1} = '    ''range'', range);';
                    mcode{end+1} = 'xlabel(labels.x);';
                    mcode{end+1} = 'ylabel(labels.y);';
                    mcode{end+1} = 'colorbar();';
                    
                case 'Near-field 3-D contour'
                    
                    % Translate the beam
                    mcode{end+1} = ['position = ', app.OriginEditField.Value, ';'];
                    mcode{end+1} = 'vbeam.basis = ''regular'';';
                    mcode{end+1} = 'vbeam = vbeam.translateXyz(position(:));';
                    mcode{end+1} = ['vbeam.basis = ''',  app.BasisDropDown.Value, ''';'];
                    mcode{end+1} = '';
                    
                    % Handle width
                    if app.AutoWidthCheckBox.Value
                        mcode{end+1} = 'range = [1,1,1]*ott.utils.nmax2ka(vbeam.Nmax)/abs(vbeam.k_medium);';
                    else
                        mcode{end+1} = ['range = [', num2str(app.Nf3dWidthSpinner.Value), ', ', ...
                            num2str(app.Nf3dHeightSpinner.Value), ', ', num2str(app.Nf3dDepthSpinner.Value), '];'];
                    end
                    
                    % Get resultion
                    mcode{end+1} = ['resolution = [', num2str(app.Nf3dXResolutionSpinner.Value), ', ', ...
                        num2str(app.Nf3dYResolutionSpinner.Value), ', ', ...
                        num2str(app.Nf3dZResolutionSpinner.Value), '];'];
                    mcode{end+1} = '';
                    
                    mcode{end+1} = 'x = linspace(-range(1), range(1), resolution(1));';
                    mcode{end+1} = 'y = linspace(-range(2), range(2), resolution(2));';
                    mcode{end+1} = 'z = linspace(-range(3), range(3), resolution(3));';
                    mcode{end+1} = '';
                    
                    mcode{end+1} = '[xx, yy, zz] = ndgrid(x, y, z);';
                    mcode{end+1} = 'xyz = [xx(:), yy(:), zz(:)];';
                    mcode{end+1} = '';
                    
                    mcode{end+1} = '% Calculate the field';
                    mcode{end+1} = 'E = vbeam.emFieldXyz(xyz.'');';
                    mcode{end+1} = '';
                    
                    mcode{end+1} = '% Convert from E to desired field';
                    mcode{end+1} = ['im = vbeam.GetVisualisationData(''', ...
                        app.FieldTypeDropDown.Value,  ''', xyz, [], E.'', []);'];
                    
                    mcode{end+1} = '% Reshape the output';
                    mcode{end+1} = 'im = reshape(im, resolution);';
                    mcode{end+1} = '';
                    
                    mcode{end+1} = '% Calculate contour plot levels';
                    mcode{end+1} = ['levels = [', app.ContoursEditField.Value, '];'];
                    mcode{end+1} = 'if numel(levels) == 1';
                        
                    mcode{end+1} = '    minim = min(im(:));';
                    mcode{end+1} = '    maxim = max(im(:));';
                        
                    mcode{end+1} = '    % Equally spaced levels';
                    if app.LogspacingCheckBox.Value
                        mcode{end+1} = '    assert(minim > 0, ''Log-scale needs positive valued field'');';
                        mcode{end+1} = '    levels = logspace(log10(minim), log10(maxim), levels(1));';
                    else
                        mcode{end+1} = '    levels = linspace(minim, maxim, levels(1));';
                    end
                    mcode{end+1} = 'elseif numel(levels) == 2 && levels(1) == levels(2)';
                    mcode{end+1} = '    levels = levels(1);';
                    mcode{end+1} = 'end';
                    mcode{end+1} = '';
        
                    mcode{end+1} = 'cmap = colormap();';
                    mcode{end+1} = '';
        
                    mcode{end+1} = 'for ii = 1:length(levels)';
                        
                        mcode{end+1} = '  % Calculate the color for this contour';
                        mcode{end+1} = '  scaled_level = (levels(ii) - min(levels))./(max(levels)-min(levels));';
                        mcode{end+1} = '  if isnan(scaled_level)';
                        mcode{end+1} = '      scaled_level = 0.5;';
                        mcode{end+1} = '  end';
                        mcode{end+1} = '  color = interp1(linspace(0, 1, size(cmap, 1)), cmap, scaled_level);';
                        mcode{end+1} = '';
                        
                        mcode{end+1} = '  % Generate the surface';
                        mcode{end+1} = '  p = patch(isosurface(im, levels(ii)), ''EdgeColor'', ''none'', ''FaceColor'', color);';
                        mcode{end+1} = '  isonormals(im,p);';
                        mcode{end+1} = '';
                        
                        mcode{end+1} = '  hold(''on'');';
                    mcode{end+1} = 'end';
            
                    mcode{end+1} = 'camlight();';
                    mcode{end+1} = 'lighting(''phong'');';
                    mcode{end+1} = 'axis(''equal'');';
                    mcode{end+1} = 'view([20, 20]);';
                    mcode{end+1} = 'hold(''off'');';
                    
                otherwise
                    error('OTT: Internal error: Unknown tab title');
            end
    
            % Convert to char array, add line endings
            mcode_str = [];
            for ii = 1:length(mcode)
                mcode_str = [mcode_str, mcode{ii}, newline]; %#ok<AGROW>
            end
            
            % Create new document
            editorDoc = matlab.desktop.editor.newDocument(mcode_str); 
            editorDoc.smartIndentContents();
            editorDoc.goToLine(1);
            
        end

        % Menu selected function: ExittoLauncherMenu
        function ExittoLauncherMenuSelected(app, event)
            ott.ui.support.ShowUiLauncher();
            delete(app);
        end

        % Menu selected function: GitHubRepoMenu
        function GitHubRepoMenuSelected(app, event)
            web('https://github.com/ilent2/ott', '-browser');
        end

        % Menu selected function: OnlineManualMenu
        function OnlineManualMenuSelected(app, event)
            web('https://ott.readthedocs.io/en/latest/', '-browser');
        end

        % Menu selected function: ReportaBugMenu
        function ReportaBugMenuSelected(app, event)
            web('https://github.com/ilent2/ott/issues', '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizebeamUIFigure = uifigure;
            app.VisualizebeamUIFigure.Name = 'Visualize beam';
            app.FileMenu = uimenu(app.VisualizebeamUIFigure);
            app.FileMenu.Text = 'File';
            app.ResettoDefaultsMenu = uimenu(app.FileMenu);
            app.ResettoDefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultsMenuSelected, true);
            app.ResettoDefaultsMenu.Text = 'Reset to Defaults';
            app.RefreshDropdownsMenu = uimenu(app.FileMenu);
            app.RefreshDropdownsMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshDropdownsMenuSelected, true);
            app.RefreshDropdownsMenu.Text = 'Refresh Dropdowns';
            app.GenerateCodeMenu = uimenu(app.FileMenu);
            app.GenerateCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCodeMenuSelected, true);
            app.GenerateCodeMenu.Separator = 'on';
            app.GenerateCodeMenu.Text = 'Generate Code...';
            app.ExittoLauncherMenu = uimenu(app.FileMenu);
            app.ExittoLauncherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExittoLauncherMenuSelected, true);
            app.ExittoLauncherMenu.Separator = 'on';
            app.ExittoLauncherMenu.Text = 'Exit to Launcher';
            app.HelpMenu = uimenu(app.VisualizebeamUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubRepoMenu = uimenu(app.HelpMenu);
            app.GitHubRepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubRepoMenuSelected, true);
            app.GitHubRepoMenu.Text = 'GitHub Repo';
            app.OnlineManualMenu = uimenu(app.HelpMenu);
            app.OnlineManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineManualMenuSelected, true);
            app.OnlineManualMenu.Text = 'Online Manual';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportaBugMenuSelected, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.TabGroup = uitabgroup(app.VisualizebeamUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FarfieldimageTab = uitab(app.TabGroup);
            app.FarfieldimageTab.Title = 'Far-field image';
            app.DirectionDropDownLabel = uilabel(app.FarfieldimageTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.FarfieldimageTab);
            app.DirectionDropDown.Items = {'+X', '-X', '+Y', '-Y', '+Z', '-Z', 'Custom'};
            app.DirectionDropDown.ItemsData = {'+X', '-X', '+Y', '-Y', '+Z', '-Z', 'Custom'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = '+Z';
            app.CustomDirectionEditFieldLabel = uilabel(app.FarfieldimageTab);
            app.CustomDirectionEditFieldLabel.Text = 'Custom Direction';
            app.CustomDirectionEditField = uieditfield(app.FarfieldimageTab, 'text');
            app.CustomDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CustomDirectionEditField.Editable = 'off';
            app.CustomDirectionEditField.Value = '[0, 0]';
            app.MappingModeDropDownLabel = uilabel(app.FarfieldimageTab);
            app.MappingModeDropDownLabel.Text = 'Mapping Mode';
            app.MappingModeDropDown = uidropdown(app.FarfieldimageTab);
            app.MappingModeDropDown.Items = {'sin(theta)', 'tan(theta)', 'theta'};
            app.MappingModeDropDown.ItemsData = {'sin', 'tan', 'theta'};
            app.MappingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.MappingModeDropDown.Value = 'sin';
            app.FFYResolutionSpinner = uispinner(app.FarfieldimageTab);
            app.FFYResolutionSpinner.LowerLimitInclusive = 'off';
            app.FFYResolutionSpinner.Limits = [0 Inf];
            app.FFYResolutionSpinner.RoundFractionalValues = 'on';
            app.FFYResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFYResolutionSpinner.Value = 80;
            app.ResolutionSpinner_3Label = uilabel(app.FarfieldimageTab);
            app.ResolutionSpinner_3Label.Text = 'Resolution';
            app.FFXResolutionSpinner = uispinner(app.FarfieldimageTab);
            app.FFXResolutionSpinner.LowerLimitInclusive = 'off';
            app.FFXResolutionSpinner.Limits = [0 Inf];
            app.FFXResolutionSpinner.RoundFractionalValues = 'on';
            app.FFXResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFXResolutionSpinner.Value = 80;
            app.FarfieldsurfaceTab = uitab(app.TabGroup);
            app.FarfieldsurfaceTab.Title = 'Far-field surface';
            app.TypeDropDownLabel = uilabel(app.FarfieldsurfaceTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FarfieldsurfaceTab);
            app.TypeDropDown.Items = {'2-D Polar plot', '3-D Polar plot', 'Sphere'};
            app.TypeDropDown.ItemsData = {'2dpolar', '3dpolar', 'sphere'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '3dpolar';
            app.NormaliseCheckBox = uicheckbox(app.FarfieldsurfaceTab);
            app.NormaliseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NormaliseCheckBox.Text = 'Normalise';
            app.AxesEditFieldLabel = uilabel(app.FarfieldsurfaceTab);
            app.AxesEditFieldLabel.Enable = 'off';
            app.AxesEditFieldLabel.Text = 'Axes';
            app.AxesEditField = uieditfield(app.FarfieldsurfaceTab, 'text');
            app.AxesEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.AxesEditField.Enable = 'off';
            app.AxesEditField.Value = '[1, 0, 0; 0, 1, 0]';
            app.ScaleLabel = uilabel(app.FarfieldsurfaceTab);
            app.ScaleLabel.Text = 'Scale';
            app.ResolutionSpinnerLabel_2 = uilabel(app.FarfieldsurfaceTab);
            app.ResolutionSpinnerLabel_2.Text = 'Resolution';
            app.FFXResolutionSpinner_2 = uispinner(app.FarfieldsurfaceTab);
            app.FFXResolutionSpinner_2.LowerLimitInclusive = 'off';
            app.FFXResolutionSpinner_2.Limits = [0 Inf];
            app.FFXResolutionSpinner_2.RoundFractionalValues = 'on';
            app.FFXResolutionSpinner_2.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFXResolutionSpinner_2.Value = 80;
            app.NearfieldsliceTab = uitab(app.TabGroup);
            app.NearfieldsliceTab.Title = 'Near-field slice';
            app.OffsetSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.NearfieldsliceTab);
            app.OffsetSpinner.Step = 0.2;
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.WidthSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.WidthSpinnerLabel.Enable = 'off';
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.NearfieldsliceTab);
            app.WidthSpinner.Step = 0.2;
            app.WidthSpinner.LowerLimitInclusive = 'off';
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.WidthSpinner.Enable = 'off';
            app.WidthSpinner.Value = 1;
            app.HeightSpinner = uispinner(app.NearfieldsliceTab);
            app.HeightSpinner.Step = 0.2;
            app.HeightSpinner.LowerLimitInclusive = 'off';
            app.HeightSpinner.Limits = [0 Inf];
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.HeightSpinner.Enable = 'off';
            app.HeightSpinner.Value = 1;
            app.ResolutionSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.NFXResolutionSpinner = uispinner(app.NearfieldsliceTab);
            app.NFXResolutionSpinner.LowerLimitInclusive = 'off';
            app.NFXResolutionSpinner.Limits = [0 Inf];
            app.NFXResolutionSpinner.RoundFractionalValues = 'on';
            app.NFXResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NFXResolutionSpinner.Value = 80;
            app.NFYResolutionSpinner = uispinner(app.NearfieldsliceTab);
            app.NFYResolutionSpinner.LowerLimitInclusive = 'off';
            app.NFYResolutionSpinner.Limits = [0 Inf];
            app.NFYResolutionSpinner.RoundFractionalValues = 'on';
            app.NFYResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NFYResolutionSpinner.Value = 80;
            app.AxisDropDownLabel = uilabel(app.NearfieldsliceTab);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.NearfieldsliceTab);
            app.AxisDropDown.Items = {'X-Y', 'X-Z', 'Y-Z', 'Custom'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'X-Y';
            app.CustomAxesEditFieldLabel = uilabel(app.NearfieldsliceTab);
            app.CustomAxesEditFieldLabel.Text = 'Custom Axes';
            app.CustomAxesEditField = uieditfield(app.NearfieldsliceTab, 'text');
            app.CustomAxesEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CustomAxesEditField.Editable = 'off';
            app.CustomAxesEditField.Value = '{[1, 0, 0], [0, 1, 0]}';
            app.AutoWidthCheckBox = uicheckbox(app.NearfieldsliceTab);
            app.AutoWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoWidthCheckBoxValueChanged, true);
            app.AutoWidthCheckBox.Text = 'Auto Width';
            app.AutoWidthCheckBox.Value = true;
            app.Nearfield3DcontourTab = uitab(app.TabGroup);
            app.Nearfield3DcontourTab.Title = 'Near-field 3-D contour';
            app.LogspacingCheckBox = uicheckbox(app.Nearfield3DcontourTab);
            app.LogspacingCheckBox.Text = 'Log-spacing';
            app.ContoursEditFieldLabel = uilabel(app.Nearfield3DcontourTab);
            app.ContoursEditFieldLabel.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.Nearfield3DcontourTab, 'text');
            app.ContoursEditField.Value = '1';
            app.Nf3dHeightSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dHeightSpinner.Step = 0.2;
            app.Nf3dHeightSpinner.LowerLimitInclusive = 'off';
            app.Nf3dHeightSpinner.Limits = [0 Inf];
            app.Nf3dHeightSpinner.Enable = 'off';
            app.Nf3dHeightSpinner.Value = 1;
            app.Nf3dYResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dYResolutionSpinner.Limits = [1 Inf];
            app.Nf3dYResolutionSpinner.Value = 40;
            app.WidthSpinner_2Label = uilabel(app.Nearfield3DcontourTab);
            app.WidthSpinner_2Label.Enable = 'off';
            app.WidthSpinner_2Label.Text = 'Width';
            app.Nf3dWidthSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dWidthSpinner.Step = 0.2;
            app.Nf3dWidthSpinner.LowerLimitInclusive = 'off';
            app.Nf3dWidthSpinner.Limits = [0 Inf];
            app.Nf3dWidthSpinner.Enable = 'off';
            app.Nf3dWidthSpinner.Value = 1;
            app.ResolutionSpinner_2Label = uilabel(app.Nearfield3DcontourTab);
            app.ResolutionSpinner_2Label.Text = 'Resolution';
            app.Nf3dXResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dXResolutionSpinner.Limits = [1 Inf];
            app.Nf3dXResolutionSpinner.Value = 40;
            app.Nf3dDepthSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dDepthSpinner.Step = 0.2;
            app.Nf3dDepthSpinner.LowerLimitInclusive = 'off';
            app.Nf3dDepthSpinner.Limits = [0 Inf];
            app.Nf3dDepthSpinner.Enable = 'off';
            app.Nf3dDepthSpinner.Value = 1;
            app.Nf3dZResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dZResolutionSpinner.Limits = [1 Inf];
            app.Nf3dZResolutionSpinner.Value = 40;
            app.OriginEditFieldLabel = uilabel(app.Nearfield3DcontourTab);
            app.OriginEditFieldLabel.Text = 'Origin';
            app.OriginEditField = uieditfield(app.Nearfield3DcontourTab, 'text');
            app.OriginEditField.Value = '[0,0,0]';
            app.AutoWidthCheckBox_2 = uicheckbox(app.Nearfield3DcontourTab);
            app.AutoWidthCheckBox_2.Text = 'Auto Width';
            app.AutoWidthCheckBox_2.Value = true;
            app.TextArea = uitextarea(app.VisualizebeamUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This GUI can be used to create a figure for visualizing beams.  Specify variable names for either or both the incident beam and the scattered beam, select the field type, and choose the visualization type.  Click generate to create a new figure.'; ''; 'Far-field visualizations should use either the incoming or outgoing basis, near field should use the regular basis.'; ''; 'Far-field image: Generate a image of the far-field.  Specify the direction to project and the mapping between spherical and Cartesian coordinates.  The custom direction can be a rotation matrix or 2 element vector (theta: rotation from z axis, phi: rotation from +x to +y).'; ''; 'Far-field surface:  Generate a polar plot, 3-D polar plot or shaded sphere plot.  Options to normalize the field.  Slice axes can be specified for the 2-D polar plot as a Nx3 matrix.'; ''; 'Near-field slice:  Generate a 2-D slice (image) of the field.  Specify the width [wavelengths], resolution, and offset along the axis from the origin [wavelengths].'; ''; 'Near-field 3-D contour: Generate a 3-D contour plot of the field.  Specify the width [wavelengths], resolution, origin [wavelengths] and contours.  Contours can be a number or the contour values (see input of contour).'};
            app.GenerateButton = uibutton(app.VisualizebeamUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ReusefigureCheckBox = uicheckbox(app.VisualizebeamUIFigure);
            app.ReusefigureCheckBox.Text = 'Re-use figure';
            app.ReusefigureCheckBox.Value = true;
            app.AutogenerateCheckBox = uicheckbox(app.VisualizebeamUIFigure);
            app.AutogenerateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutogenerateCheckBoxValueChanged, true);
            app.AutogenerateCheckBox.Text = 'Auto-generate';
            app.Panel = uipanel(app.VisualizebeamUIFigure);
            app.BasisDropDownLabel = uilabel(app.Panel);
            app.BasisDropDownLabel.Text = 'Basis';
            app.BasisDropDown = uidropdown(app.Panel);
            app.BasisDropDown.Items = {'Incoming', 'Outgoing', 'Regular'};
            app.BasisDropDown.ItemsData = {'incoming', 'outgoing', 'regular'};
            app.BasisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.BasisDropDown.Value = 'incoming';
            app.VisualiseDropDownLabel = uilabel(app.Panel);
            app.VisualiseDropDownLabel.Text = 'Visualise';
            app.VisualiseDropDown = uidropdown(app.Panel);
            app.VisualiseDropDown.Items = {'Incident Beam', 'Scattered Beam', 'Total Beam'};
            app.VisualiseDropDown.ItemsData = {'incident', 'scattered', 'total'};
            app.VisualiseDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.VisualiseDropDown.Value = 'incident';
            app.FieldTypeDropDownLabel = uilabel(app.Panel);
            app.FieldTypeDropDownLabel.Text = 'Field Type';
            app.FieldTypeDropDown = uidropdown(app.Panel);
            app.FieldTypeDropDown.Items = {'E Intensity', 'Re(Er)', 'Re(Etheta)', 'Re(Ephi)', 'Re(Ex)', 'Re(Ey)', 'Re(Ez)', '|Er|', '|Etheta|', '|Ephi|', '|Ex|', '|Ey|', '|Ez|'};
            app.FieldTypeDropDown.ItemsData = {'irradiance', 'Re(Er)', 'Re(Et)', 'Re(Ep)', 'Re(Ex)', 'Re(Ey)', 'Re(Ez)', 'Abs(Er)', 'Abs(Et)', 'Abs(Ep)', 'Abs(Ex)', 'Abs(Ey)', 'Abs(Ez)'};
            app.FieldTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FieldTypeDropDown.Value = 'irradiance';
            app.IncidentBeamDropDownLabel = uilabel(app.Panel);
            app.IncidentBeamDropDownLabel.Text = 'Incident Beam';
            app.IncidentBeamDropDown = uidropdown(app.Panel);
            app.IncidentBeamDropDown.Items = {};
            app.IncidentBeamDropDown.Editable = 'on';
            app.IncidentBeamDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.IncidentBeamDropDown.Value = {};
            app.ScatteredBeamDropDownLabel = uilabel(app.Panel);
            app.ScatteredBeamDropDownLabel.Text = 'Scattered Beam';
            app.ScatteredBeamDropDown = uidropdown(app.Panel);
            app.ScatteredBeamDropDown.Items = {};
            app.ScatteredBeamDropDown.Editable = 'on';
            app.ScatteredBeamDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ScatteredBeamDropDown.Value = {};
            app.Gauge = uigauge(app.VisualizebeamUIFigure, 'linear');
            app.Gauge.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Visualise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizebeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizebeamUIFigure)
        end
    end
end