classdef GeometricShape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeometricShapeTmatrixUIFigure   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ResettoDefaultsMenu             matlab.ui.container.Menu
        GenerateCodeMenu                matlab.ui.container.Menu
        ExittoLauncherMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GitHubRepoMenu                  matlab.ui.container.Menu
        OnlineManualMenu                matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        VariableNameEditFieldLabel      matlab.ui.control.Label
        VariableNameEditField           matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        SphereTab                       matlab.ui.container.Tab
        RadiusSpinnerLabel              matlab.ui.control.Label
        SphereRadiusSpinner             matlab.ui.control.Spinner
        TextArea_6                      matlab.ui.control.TextArea
        CylinderTab                     matlab.ui.container.Tab
        RadiusSpinner_2Label            matlab.ui.control.Label
        CylinderRadiusSpinner           matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        CylinderHeightSpinner           matlab.ui.control.Spinner
        TextArea_5                      matlab.ui.control.TextArea
        RectPrismTab                    matlab.ui.container.Tab
        XSpinnerLabel                   matlab.ui.control.Label
        RectXSpinner                    matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        RectYSpinner                    matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        RectZSpinner                    matlab.ui.control.Spinner
        TextArea_4                      matlab.ui.control.TextArea
        SuperellipsoidTab               matlab.ui.container.Tab
        XSpinner_2Label                 matlab.ui.control.Label
        EllipseXSpinner                 matlab.ui.control.Spinner
        YSpinner_2Label                 matlab.ui.control.Label
        EllipseYSpinner                 matlab.ui.control.Spinner
        ZSpinner_2Label                 matlab.ui.control.Label
        EllipseZSpinner                 matlab.ui.control.Spinner
        EWSpinnerLabel                  matlab.ui.control.Label
        EllipseEWSpinner                matlab.ui.control.Spinner
        NSSpinnerLabel                  matlab.ui.control.Label
        EllipseNSSpinner                matlab.ui.control.Spinner
        TextArea_3                      matlab.ui.control.TextArea
        AxissymmetricTab                matlab.ui.container.Tab
        RadialcoordsEditFieldLabel      matlab.ui.control.Label
        RadialcoordsEditField           matlab.ui.control.EditField
        AxialcoordsEditFieldLabel       matlab.ui.control.Label
        AxialcoordsEditField            matlab.ui.control.EditField
        TextArea_2                      matlab.ui.control.TextArea
        RelativeIndexEditFieldLabel     matlab.ui.control.Label
        RelativeIndexEditField          matlab.ui.control.EditField
        WavelengthmediumEditFieldLabel  matlab.ui.control.Label
        WavelengthmediumEditField       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        UseDefaultMethodCheckBox        matlab.ui.control.CheckBox
        UseDefaultNmaxCheckBox          matlab.ui.control.CheckBox
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        NmaxSpinnerLabel                matlab.ui.control.Label
        NmaxSpinner                     matlab.ui.control.Spinner
        Gauge                           matlab.ui.control.LinearGauge
    end


    properties (Access = public)
        Tmatrix % T-matrix generated by interface
        Shape   % Shape currently represented by interface
    end

    methods (Access = private)
    
        function WriteTmatrixToWorkspace(app)
            % Write the T-matrix to the current matlab workspace
            
            % Is there a T-matrix to write
            if isempty(app.Tmatrix)
                return;   % Nothing to do
            end
            
            % Get the variable name
            name = app.VariableNameEditField.Value;
            if isempty(name)
                return;   % Nothing to do
            end
            
            % Check the name is valid
            if ~isvarname(name)
                error('Invalid variable name in ''Variable Name'' field');
            end
            
            % Write the T-matrix to the workspace
            assignin('base', name, app.Tmatrix);
        end
    
        function SetDefaultNmaxValue(app)
            % Set the default value for the Nmax value
            
            % Check that we should be doing this
            if strcmpi(app.NmaxSpinner.Enable, 'on')
                return;
            end
            
            lambda = evalin('base', app.WavelengthmediumEditField.Value);
            radius = app.Shape.maxRadius;
            
            % Calculate and set suitable Nmax
            app.NmaxSpinner.Value = ott.utils.ka2nmax(2*pi*radius/lambda);
        end
    
        function SetDefaultMethodValue(app)
            % Set the default value for the Method value
            
            % Check that we should be doing this
            if strcmpi(app.MethodDropDown.Enable, 'on')
                return;
            end
            
            % Call the Tmatrix's method to do the work
            app.MethodDropDown.Value = ott.Tmatrix.defaultMethod(app.Shape);
        end
    
        function ProgressCallback(app, method, progress)
            
            % Update the progress bar
            switch method
                case 'pm'
                    app.Gauge.Value = progress{2} / progress{3} * 100;
                otherwise
                    app.Gauge.Value = progress*100;
            end
            
            drawnow;
        end
    
        function ResetToDefaults(app)
            
            app.VariableNameEditField.Value = 'Tmatrix';
            app.TabGroup.SelectedTab = app.SphereTab;
            app.RelativeIndexEditField.Value = '1.2';
            app.WavelengthmediumEditField.Value = '1.0';
            
            % Sphere tab
            app.SphereRadiusSpinner.Value = 1;
            
            % Cylinder tab
            app.CylinderRadiusSpinner.Value = 1;
            app.CylinderHeightSpinner.Value = 1;
            
            % Rect Prism Tab
            app.RectXSpinner.Value = 1;
            app.RectYSpinner.Value = 1;
            app.RectZSpinner.Value = 1;
            
            % Ellipse tab
            app.EllipseXSpinner.Value = 1;
            app.EllipseYSpinner.Value = 1;
            app.EllipseZSpinner.Value = 1;
            app.EllipseEWSpinner.Value = 1;
            app.EllipseNSSpinner.Value = 1;
            
            % Axisym tab
            app.RadialcoordsEditField.Value = '0, 1, 1, 0';
            app.AxialcoordsEditField.Value = '1, 1, -1, -1';
            
            app.UseDefaultMethodCheckBox.Value = 1;
            app.UseDefaultNmaxCheckBox.Value = 1;
            app.MethodDropDown.Value = 'dda';
            app.NmaxSpinner.Value = 0;
            
            % Reset Gauge
            app.Gauge.Value = 0;
            
            % Initialize shape preview
            app.ShapeValueChanged();
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialize the T-matrix (initially none)
            app.Tmatrix = [];
            
            % Initialize shape preview and auto Nmax/Method selection
            app.ShapeValueChanged();
        end

        % Value changed function: UseDefaultMethodCheckBox
        function UseDefaultMethodCheckBoxValueChanged(app, event)
            
            % Enable/disable the drop down box
            if app.UseDefaultMethodCheckBox.Value
                app.MethodDropDown.Enable = 'off';
                
                % Set the Method to the auto-value
                app.SetDefaultMethodValue();
            else
                app.MethodDropDown.Enable = 'on';
            end
            
            
        end

        % Value changed function: UseDefaultNmaxCheckBox
        function UseDefaultNmaxCheckBoxValueChanged(app, event)

            % Enable/disable the drop down box
            if app.UseDefaultNmaxCheckBox.Value
                app.NmaxSpinner.Enable = 'off';
                
                % Set the Nmax spinner to the auto-value
                app.SetDefaultNmaxValue();
            else
                app.NmaxSpinner.Enable = 'on';
            end
            
        end

        % Callback function: AxialcoordsEditField, 
        % CylinderHeightSpinner, CylinderRadiusSpinner, 
        % EllipseEWSpinner, EllipseNSSpinner, EllipseXSpinner, 
        % EllipseYSpinner, EllipseZSpinner, RadialcoordsEditField, 
        % RectXSpinner, RectYSpinner, RectZSpinner, SphereRadiusSpinner, 
        % TabGroup
        function ShapeValueChanged(app, event)
            
            % Update the shape preview axes
            switch app.TabGroup.SelectedTab.Title
                case 'Sphere'
                    app.Shape = ott.shapes.Sphere(app.SphereRadiusSpinner.Value);
                case 'Cylinder'
                    app.Shape = ott.shapes.Cylinder(app.CylinderRadiusSpinner.Value, app.CylinderHeightSpinner.Value);
                case 'Rect. Prism'
                    x = app.RectXSpinner.Value;
                    y = app.RectYSpinner.Value;
                    z = app.RectZSpinner.Value;
                    app.Shape = ott.shapes.RectangularPrism(x, y, z);
                    
                case 'Superellipsoid'
                    x = app.EllipseXSpinner.Value;
                    y = app.EllipseYSpinner.Value;
                    z = app.EllipseZSpinner.Value;
                    ew = app.EllipseEWSpinner.Value;
                    ns = app.EllipseNSSpinner.Value;
                    app.Shape = ott.shapes.Superellipsoid(x, y, z, ew, ns);
                    
                case 'Axis symmetric'
                    rho = evalin('base', ['[', app.RadialcoordsEditField.Value, ']']);
                    z = evalin('base', ['[', app.AxialcoordsEditField.Value, ']']);
                    app.Shape = ott.shapes.AxisymLerp([rho(:), z(:)]);
                    
                otherwise
                    error(['Internal error: Unknown tab selcted: ' app.TabGroup.SelectedTab.Title]);
            end
            
            % Display the shape
            [X, Y, Z] = app.Shape.surf();
            surfl(app.UIAxes, X, Y, Z);
            shading(app.UIAxes, 'interp');
            axis(app.UIAxes, 'equal');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            
            % Update the auto-Nmax selection
            % Update the auto-Methdo selection
            app.SetDefaultMethodValue();
            app.SetDefaultNmaxValue();
            
        end

        % Value changed function: VariableNameEditField
        function VariableNameEditFieldValueChanged(app, event)
            % Write the T-matrix to the new variable name
            app.WriteTmatrixToWorkspace();
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Get free-form text field values
            index_relative = evalin('base', app.RelativeIndexEditField.Value);
            wavelength_medium = evalin('base', app.WavelengthmediumEditField.Value);
            
            % Disable the button
            app.GenerateButton.Enable = 'off';
            app.Gauge.Value = 0;
            app.Gauge.BackgroundColor = [1, 1, 1];
            drawnow;
            
            try
            
                % Generate the T-matrix
                app.Tmatrix = ott.Tmatrix.simple(app.Shape, ...
                    'Nmax', app.NmaxSpinner.Value, ...
                    'method', app.MethodDropDown.Value, ...
                    'index_relative', index_relative, ...
                    'wavelength_medium', wavelength_medium, ...
                    'progress_callback', @(p) app.ProgressCallback(app.MethodDropDown.Value, p));
                
            catch ME
                
                app.GenerateButton.Enable = 'on';
                app.Gauge.BackgroundColor = [1, 0, 0];
                drawnow;
                
                rethrow(ME);
            end
            
            % Enable the button
            app.GenerateButton.Enable = 'on';
            app.Gauge.Value = 100;
            drawnow;
            
            % Save the T-matrix to the workspace
            app.WriteTmatrixToWorkspace();
        end

        % Menu selected function: ResettoDefaultsMenu
        function ResettoDefaultsMenuSelected(app, event)
            app.ResetToDefaults();
        end

        % Menu selected function: GenerateCodeMenu
        function GenerateCodeMenuSelected(app, event)
            
            mcode = {};
            
            % Document header
            mcode{end+1} = '% Generate a T-matrix for a simple Geometric shape';
            mcode{end+1} = '% Automatically generated using the Optical Tweezers Toolbox';
            mcode{end+1} = '';
            
            % Setup variables
            mcode{end+1} = ['Nmax = ', num2str(app.NmaxSpinner.Value), ';'];
            mcode{end+1} = ['method = ''', app.MethodDropDown.Value, ''';'];
            mcode{end+1} = ['index_relative = ', app.RelativeIndexEditField.Value, ';'];
            mcode{end+1} = ['wavelength_medium = ', app.WavelengthmediumEditField.Value, ';'];
            mcode{end+1} = '';
            
            % Generate shape
            mcode{end+1} = '% Generate shape';
            switch app.TabGroup.SelectedTab.Title
                case 'Sphere'
                    mcode{end+1} = ['radius = ', num2str(app.SphereRadiusSpinner.Value), ';'];
                    mcode{end+1} = 'shape = ott.shapes.Sphere(radius);';
                    
                case 'Cylinder'
                    mcode{end+1} = ['radius = ', num2str(app.CylinderRadiusSpinner.Value), ';'];
                    mcode{end+1} = ['height = ', num2str(app.CylinderHeightSpinner.Value), ';'];
                    mcode{end+1} = 'shape = ott.shapes.Cylinder(radius, height);';
                    
                case 'Rect. Prism'
                    mcode{end+1} = ['x = ', num2str(app.RectXSpinner.Value), ';'];
                    mcode{end+1} = ['y = ', num2str(app.RectYSpinner.Value), ';'];
                    mcode{end+1} = ['z = ', num2str(app.RectZSpinner.Value), ';'];
                    mcode{end+1} = 'shape = ott.shapes.RectangularPrism(x, y, z);';
                    
                case 'Superellipsoid'
                    mcode{end+1} = ['x = ', num2str(app.EllipseXSpinner.Value), ';'];
                    mcode{end+1} = ['y = ', num2str(app.EllipseYSpinner.Value), ';'];
                    mcode{end+1} = ['z = ', num2str(app.EllipseZSpinner.Value), ';'];
                    mcode{end+1} = ['ew = ', num2str(app.EllipseEWSpinner.Value), ';'];
                    mcode{end+1} = ['ns = ', num2str(app.EllipseNSSpinner.Value), ';'];
                    mcode{end+1} = 'shape = ott.shapes.Superellipsoid(x, y, z, ew, ns);';
                    
                case 'Axis symmetric'
                    mcode{end+1} = ['rho = [', app.RadialcoordsEditField.Value, '];'];
                    mcode{end+1} = ['z = [', app.AxialcoordsEditField.Value, '];'];
                    mcode{end+1} = 'shape = ott.shapes.AxisymLerp([rho(:), z(:)]);';
                    
                otherwise
                    error(['Internal error: Unknown tab selcted: ' app.TabGroup.SelectedTab.Title]);
            end
            mcode{end+1} = '';
            
            % Generate the T-matrix
            mcode{end+1} = '% Generate T-matrix';
            mcode{end+1} = 'Tmatrix = ott.Tmatrix.simple(shape, ...';
            mcode{end+1} = '    ''Nmax'', Nmax, ...';
            mcode{end+1} = '    ''method'', method, ...';
            mcode{end+1} = '    ''index_relative'', index_relative, ...';
            mcode{end+1} = '    ''wavelength_medium'', wavelength_medium);';
            
            % Convert to char array, add line endings
            mcode_str = [];
            for ii = 1:length(mcode)
                mcode_str = [mcode_str, mcode{ii}, newline]; %#ok<AGROW>
            end
            
            % Create new document
            editorDoc = matlab.desktop.editor.newDocument(mcode_str); 
            editorDoc.smartIndentContents();
            editorDoc.goToLine(1);
                
        end

        % Menu selected function: ExittoLauncherMenu
        function ExittoLauncherMenuSelected(app, event)
            ott.ui.support.ShowUiLauncher();
            delete(app);
        end

        % Menu selected function: GitHubRepoMenu
        function GitHubRepoMenuSelected(app, event)
            web('https://github.com/ilent2/ott', '-browser');
        end

        % Menu selected function: OnlineManualMenu
        function OnlineManualMenuSelected(app, event)
            web('https://ott.readthedocs.io/en/latest/', '-browser');
        end

        % Menu selected function: ReportaBugMenu
        function ReportaBugMenuSelected(app, event)
            web('https://github.com/ilent2/ott/issues', '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeometricShapeTmatrixUIFigure = uifigure;
            app.GeometricShapeTmatrixUIFigure.Name = 'Geometric Shape T-matrix';
            app.FileMenu = uimenu(app.GeometricShapeTmatrixUIFigure);
            app.FileMenu.Text = 'File';
            app.ResettoDefaultsMenu = uimenu(app.FileMenu);
            app.ResettoDefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultsMenuSelected, true);
            app.ResettoDefaultsMenu.Text = 'Reset to Defaults';
            app.GenerateCodeMenu = uimenu(app.FileMenu);
            app.GenerateCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCodeMenuSelected, true);
            app.GenerateCodeMenu.Separator = 'on';
            app.GenerateCodeMenu.Text = 'Generate Code...';
            app.ExittoLauncherMenu = uimenu(app.FileMenu);
            app.ExittoLauncherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExittoLauncherMenuSelected, true);
            app.ExittoLauncherMenu.Separator = 'on';
            app.ExittoLauncherMenu.Text = 'Exit to Launcher';
            app.HelpMenu = uimenu(app.GeometricShapeTmatrixUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubRepoMenu = uimenu(app.HelpMenu);
            app.GitHubRepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubRepoMenuSelected, true);
            app.GitHubRepoMenu.Text = 'GitHub Repo';
            app.OnlineManualMenu = uimenu(app.HelpMenu);
            app.OnlineManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineManualMenuSelected, true);
            app.OnlineManualMenu.Text = 'Online Manual';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportaBugMenuSelected, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.Panel = uipanel(app.GeometricShapeTmatrixUIFigure);
            app.VariableNameEditFieldLabel = uilabel(app.Panel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.Panel, 'text');
            app.VariableNameEditField.ValueChangedFcn = createCallbackFcn(app, @VariableNameEditFieldValueChanged, true);
            app.VariableNameEditField.Value = 'Tmatrix';
            app.TabGroup = uitabgroup(app.Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.SphereTab = uitab(app.TabGroup);
            app.SphereTab.Title = 'Sphere';
            app.RadiusSpinnerLabel = uilabel(app.SphereTab);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.SphereRadiusSpinner = uispinner(app.SphereTab);
            app.SphereRadiusSpinner.Step = 0.1;
            app.SphereRadiusSpinner.LowerLimitInclusive = 'off';
            app.SphereRadiusSpinner.Limits = [0 Inf];
            app.SphereRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.SphereRadiusSpinner.Value = 1;
            app.TextArea_6 = uitextarea(app.SphereTab);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Spherical particle. The elements of the T-matrix are semi-analytical for spheres, corresponding to the Mie coefficients.'};
            app.CylinderTab = uitab(app.TabGroup);
            app.CylinderTab.Title = 'Cylinder';
            app.RadiusSpinner_2Label = uilabel(app.CylinderTab);
            app.RadiusSpinner_2Label.Text = 'Radius';
            app.CylinderRadiusSpinner = uispinner(app.CylinderTab);
            app.CylinderRadiusSpinner.Step = 0.1;
            app.CylinderRadiusSpinner.LowerLimitInclusive = 'off';
            app.CylinderRadiusSpinner.Limits = [0 Inf];
            app.CylinderRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.CylinderRadiusSpinner.Value = 1;
            app.HeightSpinnerLabel = uilabel(app.CylinderTab);
            app.HeightSpinnerLabel.Text = 'Height';
            app.CylinderHeightSpinner = uispinner(app.CylinderTab);
            app.CylinderHeightSpinner.Step = 0.1;
            app.CylinderHeightSpinner.LowerLimitInclusive = 'off';
            app.CylinderHeightSpinner.Limits = [0 Inf];
            app.CylinderHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.CylinderHeightSpinner.Value = 1;
            app.TextArea_5 = uitextarea(app.CylinderTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Generate a cylinder with radius and height.  The shape is aligned to the beam axis.'};
            app.RectPrismTab = uitab(app.TabGroup);
            app.RectPrismTab.Title = 'Rect. Prism';
            app.XSpinnerLabel = uilabel(app.RectPrismTab);
            app.XSpinnerLabel.Text = 'X';
            app.RectXSpinner = uispinner(app.RectPrismTab);
            app.RectXSpinner.Step = 0.1;
            app.RectXSpinner.LowerLimitInclusive = 'off';
            app.RectXSpinner.Limits = [0 Inf];
            app.RectXSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.RectXSpinner.Value = 1;
            app.YSpinnerLabel = uilabel(app.RectPrismTab);
            app.YSpinnerLabel.Text = 'Y';
            app.RectYSpinner = uispinner(app.RectPrismTab);
            app.RectYSpinner.Step = 0.1;
            app.RectYSpinner.LowerLimitInclusive = 'off';
            app.RectYSpinner.Limits = [0 Inf];
            app.RectYSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.RectYSpinner.Value = 1;
            app.ZSpinnerLabel = uilabel(app.RectPrismTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.RectZSpinner = uispinner(app.RectPrismTab);
            app.RectZSpinner.Step = 0.1;
            app.RectZSpinner.LowerLimitInclusive = 'off';
            app.RectZSpinner.Limits = [0 Inf];
            app.RectZSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.RectZSpinner.Value = 1;
            app.TextArea_4 = uitextarea(app.RectPrismTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Rectangular prism and cubes.  X, Y, Z describes the width in each major axis.'};
            app.SuperellipsoidTab = uitab(app.TabGroup);
            app.SuperellipsoidTab.Title = 'Superellipsoid';
            app.XSpinner_2Label = uilabel(app.SuperellipsoidTab);
            app.XSpinner_2Label.Text = 'X';
            app.EllipseXSpinner = uispinner(app.SuperellipsoidTab);
            app.EllipseXSpinner.Step = 0.1;
            app.EllipseXSpinner.LowerLimitInclusive = 'off';
            app.EllipseXSpinner.Limits = [0 Inf];
            app.EllipseXSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.EllipseXSpinner.Value = 1;
            app.YSpinner_2Label = uilabel(app.SuperellipsoidTab);
            app.YSpinner_2Label.Text = 'Y';
            app.EllipseYSpinner = uispinner(app.SuperellipsoidTab);
            app.EllipseYSpinner.Step = 0.1;
            app.EllipseYSpinner.LowerLimitInclusive = 'off';
            app.EllipseYSpinner.Limits = [0 Inf];
            app.EllipseYSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.EllipseYSpinner.Value = 1;
            app.ZSpinner_2Label = uilabel(app.SuperellipsoidTab);
            app.ZSpinner_2Label.Text = 'Z';
            app.EllipseZSpinner = uispinner(app.SuperellipsoidTab);
            app.EllipseZSpinner.Step = 0.1;
            app.EllipseZSpinner.LowerLimitInclusive = 'off';
            app.EllipseZSpinner.Limits = [0 Inf];
            app.EllipseZSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.EllipseZSpinner.Value = 1;
            app.EWSpinnerLabel = uilabel(app.SuperellipsoidTab);
            app.EWSpinnerLabel.Text = 'EW';
            app.EllipseEWSpinner = uispinner(app.SuperellipsoidTab);
            app.EllipseEWSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.EllipseEWSpinner.Value = 1;
            app.NSSpinnerLabel = uilabel(app.SuperellipsoidTab);
            app.NSSpinnerLabel.Text = 'NS';
            app.EllipseNSSpinner = uispinner(app.SuperellipsoidTab);
            app.EllipseNSSpinner.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.EllipseNSSpinner.Value = 1;
            app.TextArea_3 = uitextarea(app.SuperellipsoidTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Superellipsoids and ellipsoids.  X, Y, Z describe the scaling of the three major axes.  EW and NS describe the east-west and north-south smoothness of a superellipsoid.'};
            app.AxissymmetricTab = uitab(app.TabGroup);
            app.AxissymmetricTab.Title = 'Axis symmetric';
            app.RadialcoordsEditFieldLabel = uilabel(app.AxissymmetricTab);
            app.RadialcoordsEditFieldLabel.Text = 'Radial coords.';
            app.RadialcoordsEditField = uieditfield(app.AxissymmetricTab, 'text');
            app.RadialcoordsEditField.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.RadialcoordsEditField.Value = '0, 1, 1, 0';
            app.AxialcoordsEditFieldLabel = uilabel(app.AxissymmetricTab);
            app.AxialcoordsEditFieldLabel.Text = 'Axial coords.';
            app.AxialcoordsEditField = uieditfield(app.AxissymmetricTab, 'text');
            app.AxialcoordsEditField.ValueChangedFcn = createCallbackFcn(app, @ShapeValueChanged, true);
            app.AxialcoordsEditField.Value = '1, 1, -1, -1';
            app.TextArea_2 = uitextarea(app.AxissymmetricTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Axis symmetric particles are described by two arrays: the radial coordinate and the axial coordinate of points (vertices). '};
            app.RelativeIndexEditFieldLabel = uilabel(app.Panel);
            app.RelativeIndexEditFieldLabel.Text = 'Relative Index';
            app.RelativeIndexEditField = uieditfield(app.Panel, 'text');
            app.RelativeIndexEditField.Value = '1.2';
            app.WavelengthmediumEditFieldLabel = uilabel(app.Panel);
            app.WavelengthmediumEditFieldLabel.Text = 'Wavelength (medium)';
            app.WavelengthmediumEditField = uieditfield(app.Panel, 'text');
            app.WavelengthmediumEditField.Value = '1.0';
            app.UIAxes = uiaxes(app.GeometricShapeTmatrixUIFigure);
            title(app.UIAxes, 'Shape Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel_2 = uipanel(app.GeometricShapeTmatrixUIFigure);
            app.GenerateButton = uibutton(app.Panel_2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.UseDefaultMethodCheckBox = uicheckbox(app.Panel_2);
            app.UseDefaultMethodCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultMethodCheckBoxValueChanged, true);
            app.UseDefaultMethodCheckBox.Text = 'Use Default';
            app.UseDefaultMethodCheckBox.Value = true;
            app.UseDefaultNmaxCheckBox = uicheckbox(app.Panel_2);
            app.UseDefaultNmaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultNmaxCheckBoxValueChanged, true);
            app.UseDefaultNmaxCheckBox.Text = 'Use Default';
            app.UseDefaultNmaxCheckBox.Value = true;
            app.MethodDropDownLabel = uilabel(app.Panel_2);
            app.MethodDropDownLabel.Enable = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel_2);
            app.MethodDropDown.Items = {'DDA', 'Mie', 'SMARTIES', 'PM', 'EBCM'};
            app.MethodDropDown.ItemsData = {'dda', 'mie', 'smarties', 'pm', 'ebcm'};
            app.MethodDropDown.Enable = 'off';
            app.MethodDropDown.Value = 'dda';
            app.NmaxSpinnerLabel = uilabel(app.Panel_2);
            app.NmaxSpinnerLabel.Enable = 'off';
            app.NmaxSpinnerLabel.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.Panel_2);
            app.NmaxSpinner.Enable = 'off';
            app.Gauge = uigauge(app.Panel_2, 'linear');
            app.Gauge.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GeometricShape

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeometricShapeTmatrixUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeometricShapeTmatrixUIFigure)
        end
    end
end