classdef StlObjLoader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadSTLWavefrontOBJfileUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ResettoDefaultsMenu             matlab.ui.container.Menu
        GenerateCodeMenu                matlab.ui.container.Menu
        ExittoLauncherMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GitHubRepoMenu                  matlab.ui.container.Menu
        OnlineManualMenu                matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        NmaxSpinnerLabel                matlab.ui.control.Label
        NmaxSpinner                     matlab.ui.control.Spinner
        AutoCheckBox                    matlab.ui.control.CheckBox
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        Gauge                           matlab.ui.control.LinearGauge
        Button                          matlab.ui.control.Button
        VariableNameEditField_2Label    matlab.ui.control.Label
        VariableNameEditField_2         matlab.ui.control.EditField
        WavelengthmediumEditFieldLabel  matlab.ui.control.Label
        WavelengthmediumEditField       matlab.ui.control.EditField
        ScaleSpinnerLabel               matlab.ui.control.Label
        ScaleSpinner                    matlab.ui.control.Spinner
        SpacingSpinnerLabel             matlab.ui.control.Label
        SpacingSpinner                  matlab.ui.control.Spinner
        RelativeIndexEditFieldLabel     matlab.ui.control.Label
        RelativeIndexEditField          matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Shape % Shape loaded by the GUI
        Tmatrix % T-matrix generated by GUI
    end

    methods (Access = private)
    
        function LoadMeshUpdateDisplay(app)
            % Load the mesh and update the display
            
            % Get the fielname and check we have work to do
            filename = app.FilenameEditField.Value;
            if isempty(filename)
                app.Shape = [];
                return;
            end
            
            % Load the shape by extension
            [~, ~, ext] = fileparts(filename);
            switch ext
                case '.obj'
                    app.Shape = ott.shapes.WavefrontObj(filename);
                case '.stl'
                    app.Shape = ott.shapes.StlLoader(filename);
                otherwise
                    error('Unknown file extension, must be obj or stl');
            end
            
            % Show a visualisation of the surface
            app.Shape.surf('axes', app.UIAxes, 'surfoptions', {'EdgeColor', 'none'});
            view(app.UIAxes, [20, 20]);
            axis(app.UIAxes, 'equal');
            xlabel(app.UIAxes, 'X Position');
            ylabel(app.UIAxes, 'Y Position');
            zlabel(app.UIAxes, 'Z Position');
            
            % Change the default voxel spacing/scale
            app.SpacingSpinner.Value = app.Shape.maxRadius/5;
            app.ScaleSpinner.Value = 1.0;
            
            % Update visualsiation of the voxels
            app.UpdateVoxelDisplay();
            
        end
    
        function UpdateVoxelDisplay(app)
            % Update the visualisation of the voxels
            
            % Check we have work to do
            if isempty(app.Shape)
                return;
            end
            
            wavelength_m = evalin('base', app.WavelengthmediumEditField.Value);
            
            % Generate a visualisation of the voxels
            app.Shape.voxels('scale', app.ScaleSpinner.Value ./ wavelength_m, ...
                'spacing', app.SpacingSpinner.Value, ...
                'axes', app.UIAxes_2);
            view(app.UIAxes_2, [20, 20]);
            axis(app.UIAxes_2, 'equal');
            xlabel(app.UIAxes_2, 'X Position [\lambda_m]');
            ylabel(app.UIAxes_2, 'Y Position [\lambda_m]');
            zlabel(app.UIAxes_2, 'Z Position [\lambda_m]');
            
            % Update auto-Nmax
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Value = ott.utils.ka2nmax(2*pi*app.Shape.maxRadius*app.ScaleSpinner.Value./wavelength_m);
            end
        end
        
        function ProgressCallback(app, data)
            
            % Calculate the progress
            I = find(data.mrange == data.m, 1, 'first');
            progress = (I-1) ./ numel(data.mrange);
            
            % Update the guage
            app.Gauge.Value = progress*100;
            drawnow;
        end
    
        function ResetToDefaults(app)
            
            % Clear everything else
            app.NmaxSpinner.Value = 20;
            app.AutoCheckBox.Value = 1;
            app.FilenameEditField.Value = '';
            app.VariableNameEditField_2.Value = 'Tmatrix';
            app.WavelengthmediumEditField.Value = '1.0';
            app.ScaleSpinner.Value = 1;
            app.SpacingSpinner.Value = 0.1;
            app.RelativeIndexEditField.Value = '1.2';
            
            % Reset Gauge
            app.Gauge.Value = 0;
            
            % Clear axes
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Shape = [];
            app.Tmatrix = [];
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            % Display a file chooser dialog
            [file, path] = uigetfile({'*.obj;*.stl', 'CAD file'}, 'Select a File');
            
            % Put the GUI back on top
            figure(app.LoadSTLWavefrontOBJfileUIFigure);
            
            % Check if the user selected a file
            if ~isequal(file,0)
                
                % Update the filename field and process the event
                app.FilenameEditField.Value = fullfile(path, file);
                
                % Load the mesh and update the display
                app.LoadMeshUpdateDisplay();
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.LoadMeshUpdateDisplay();
        end

        % Value changed function: ScaleSpinner, SpacingSpinner
        function VoxelDisplayValueChanged(app, event)
            app.UpdateVoxelDisplay();
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Enable = 'off';
                
                % Reset the auto value
                if ~isempty(app.Shape)
                    wavelength_m = evalin('base', app.WavelengthmediumEditField.Value);
                    app.NmaxSpinner.Value = ott.utils.ka2nmax(2*pi*app.Shape.maxRadius*app.ScaleSpinner.Value./wavelength_m);
                end
            else
                app.NmaxSpinner.Enable = 'on';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Check we have work to do
            if isempty(app.Shape)
                error('No valid shape loaded');
            end
            
            % Intialize the Guage
            app.Gauge.Value = 0;
            app.Gauge.BackgroundColor = [1, 1, 1];
            
            % Turn off the button (make the user wait)
            app.GenerateButton.Enable = 'off';
            
            % Update the GUI
            drawnow;
            
            % Get output variable name
            outputName = app.VariableNameEditField_2.Value;
            if ~isvarname(outputName)
                error('Must supply a valid variable name for output');
            end
            
            wavelength_m = evalin('base', app.WavelengthmediumEditField.Value);
            index_relative = evalin('base', app.RelativeIndexEditField.Value);
            
            try
            
                % Get voxel locations
                xyz = app.Shape.voxels('scale', app.ScaleSpinner.Value ./ wavelength_m, ...
                    'spacing', app.SpacingSpinner.Value);
                
                % Generate T-matrix with DDA
                app.Tmatrix = ott.TmatrixDda(xyz, 'wavelength_medium', wavelength_m, ...
                    'index_relative', index_relative, ...
                    'Nmax', app.NmaxSpinner.Value, ...
                    'spacing', app.SpacingSpinner.Value, ...
                    'progress_callback', @(p) app.ProgressCallback(p));
                
            catch ME
                
                % Show a graphical queue for an error
                app.Gauge.BackgroundColor = [1, 0, 0];
                
                % Re-enable button
                app.GenerateButton.Enable = 'on';
                
                % UPdate the GUI
                drawnow;
                
                % Retrhwo error
                rethrow(ME);
            end
            
            % Update Guage one final time
            app.Gauge.Value = 100;
                
            % Re-enable button
            app.GenerateButton.Enable = 'on';
                
            % Update the GUI
            drawnow;
            
            % Store the result
            assignin('base', outputName, app.Tmatrix);
            
        end

        % Menu selected function: ResettoDefaultsMenu
        function ResettoDefaultsMenuSelected(app, event)
            app.ResetToDefaults();
        end

        % Menu selected function: GenerateCodeMenu
        function GenerateCodeMenuSelected(app, event)

            mcode = {};
            
            % Document header
            mcode{end+1} = '% Generate a T-matrix from a CAD file';
            mcode{end+1} = '% Automatically generated using the Optical Tweezers Toolbox';
            mcode{end+1} = '';
            
            % Variables
            mcode{end+1} = ['wavelength = ', app.WavelengthmediumEditField.Value, ';'];
            mcode{end+1} = ['index_relative = ', app.RelativeIndexEditField.Value, ';'];
            mcode{end+1} = ['scale = ', num2str(app.ScaleSpinner.Value), ';'];
            mcode{end+1} = ['spacing = ', num2str(app.SpacingSpinner.Value), ';'];
            mcode{end+1} = ['filename = ''', app.FilenameEditField.Value, ''';'];
            mcode{end+1} = ['Nmax = ', num2str(app.NmaxSpinner.Value), ';'];
            
            mcode{end+1} = '';
            mcode{end+1} = '% Load the mesh file';
            
            % Get the fielname and check we have work to do
            filename = app.FilenameEditField.Value;
            if isempty(filename)
                error('Must supply filename');
            end
            
            % Load the shape by extension
            [~, ~, ext] = fileparts(filename);
            switch ext
                case '.obj'
                    mcode{end+1} = 'shape = ott.shapes.WavefrontObj(filename);';
                case '.stl'
                    mcode{end+1} = 'shape = ott.shapes.StlLoader(filename);';
                otherwise
                    error('Unknown file extension, must be obj or stl');
            end
            
            % Calculate voxels
            mcode{end+1} = '';
            mcode{end+1} = '% Calculate voxels for DDA';
            mcode{end+1} = 'xyz = shape.voxels(''scale'', scale ./ wavelength, ''spacing'', spacing);';
            
                
            % Generate T-matrix with DDA
            mcode{end+1} = '';
            mcode{end+1} = '% Generate T-matrix';
            mcode{end+1} = 'Tmatrix = ott.TmatrixDda(xyz, ''wavelength_medium'', wavelength, ...';
            mcode{end+1} = '    ''index_relative'', index_relative, ''Nmax'', Nmax, ''spacing'', spacing);';
            
            % Convert to char array, add line endings
            mcode_str = [];
            for ii = 1:length(mcode)
                mcode_str = [mcode_str, mcode{ii}, newline]; %#ok<AGROW>
            end
            
            % Create new document
            editorDoc = matlab.desktop.editor.newDocument(mcode_str); 
            editorDoc.smartIndentContents();
            editorDoc.goToLine(1);
            
        end

        % Menu selected function: ExittoLauncherMenu
        function ExittoLauncherMenuSelected(app, event)
            ott.ui.support.ShowUiLauncher();
            delete(app);
        end

        % Menu selected function: GitHubRepoMenu
        function GitHubRepoMenuSelected(app, event)
            web('https://github.com/ilent2/ott', '-browser');
        end

        % Menu selected function: OnlineManualMenu
        function OnlineManualMenuSelected(app, event)
            web('https://ott.readthedocs.io/en/latest/', '-browser');
        end

        % Menu selected function: ReportaBugMenu
        function ReportaBugMenuSelected(app, event)
            web('https://github.com/ilent2/ott/issues', '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadSTLWavefrontOBJfileUIFigure = uifigure;
            app.LoadSTLWavefrontOBJfileUIFigure.Name = 'Load STL/Wavefront OBJ file';
            app.FileMenu = uimenu(app.LoadSTLWavefrontOBJfileUIFigure);
            app.FileMenu.Text = 'File';
            app.ResettoDefaultsMenu = uimenu(app.FileMenu);
            app.ResettoDefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultsMenuSelected, true);
            app.ResettoDefaultsMenu.Text = 'Reset to Defaults';
            app.GenerateCodeMenu = uimenu(app.FileMenu);
            app.GenerateCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCodeMenuSelected, true);
            app.GenerateCodeMenu.Separator = 'on';
            app.GenerateCodeMenu.Text = 'Generate Code...';
            app.ExittoLauncherMenu = uimenu(app.FileMenu);
            app.ExittoLauncherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExittoLauncherMenuSelected, true);
            app.ExittoLauncherMenu.Separator = 'on';
            app.ExittoLauncherMenu.Text = 'Exit to Launcher';
            app.HelpMenu = uimenu(app.LoadSTLWavefrontOBJfileUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubRepoMenu = uimenu(app.HelpMenu);
            app.GitHubRepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubRepoMenuSelected, true);
            app.GitHubRepoMenu.Text = 'GitHub Repo';
            app.OnlineManualMenu = uimenu(app.HelpMenu);
            app.OnlineManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineManualMenuSelected, true);
            app.OnlineManualMenu.Text = 'Online Manual';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportaBugMenuSelected, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.Panel = uipanel(app.LoadSTLWavefrontOBJfileUIFigure);
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NmaxSpinnerLabel = uilabel(app.Panel);
            app.NmaxSpinnerLabel.Enable = 'off';
            app.NmaxSpinnerLabel.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.Panel);
            app.NmaxSpinner.Limits = [1 Inf];
            app.NmaxSpinner.RoundFractionalValues = 'on';
            app.NmaxSpinner.Enable = 'off';
            app.NmaxSpinner.Value = 20;
            app.AutoCheckBox = uicheckbox(app.Panel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.FilenameEditFieldLabel = uilabel(app.Panel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Panel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.VariableNameEditField_2Label = uilabel(app.Panel);
            app.VariableNameEditField_2Label.Text = 'Variable Name';
            app.VariableNameEditField_2 = uieditfield(app.Panel, 'text');
            app.VariableNameEditField_2.Value = 'Tmatrix';
            app.WavelengthmediumEditFieldLabel = uilabel(app.Panel);
            app.WavelengthmediumEditFieldLabel.Text = 'Wavelength (medium)';
            app.WavelengthmediumEditField = uieditfield(app.Panel, 'text');
            app.WavelengthmediumEditField.Value = '1.0';
            app.ScaleSpinnerLabel = uilabel(app.Panel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.LowerLimitInclusive = 'off';
            app.ScaleSpinner.Limits = [0 Inf];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @VoxelDisplayValueChanged, true);
            app.ScaleSpinner.Value = 1;
            app.SpacingSpinnerLabel = uilabel(app.Panel);
            app.SpacingSpinnerLabel.Text = 'Spacing';
            app.SpacingSpinner = uispinner(app.Panel);
            app.SpacingSpinner.Step = 0.1;
            app.SpacingSpinner.LowerLimitInclusive = 'off';
            app.SpacingSpinner.Limits = [0 Inf];
            app.SpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @VoxelDisplayValueChanged, true);
            app.SpacingSpinner.Value = 0.1;
            app.RelativeIndexEditFieldLabel = uilabel(app.Panel);
            app.RelativeIndexEditFieldLabel.Text = 'Relative Index';
            app.RelativeIndexEditField = uieditfield(app.Panel, 'text');
            app.RelativeIndexEditField.Value = '1.2';
            app.UIAxes = uiaxes(app.LoadSTLWavefrontOBJfileUIFigure);
            xlabel(app.UIAxes, 'X Position [\lambda_0]')
            ylabel(app.UIAxes, 'Y Position [\lambda_0]')
            app.UIAxes_2 = uiaxes(app.LoadSTLWavefrontOBJfileUIFigure);
            xlabel(app.UIAxes_2, 'Z Position [\lambda_0]')
            ylabel(app.UIAxes_2, 'X Position [\lambda_0]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = StlObjLoader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadSTLWavefrontOBJfileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadSTLWavefrontOBJfileUIFigure)
        end
    end
end