classdef Scatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScatterBeamUIFigure          matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ResettoDefaultsMenu          matlab.ui.container.Menu
        RefreshDropdownsMenu         matlab.ui.container.Menu
        GenerateCodeMenu             matlab.ui.container.Menu
        ExittoLauncherMenu           matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        GitHubRepoMenu               matlab.ui.container.Menu
        OnlineManualMenu             matlab.ui.container.Menu
        ReportaBugMenu               matlab.ui.container.Menu
        Panel                        matlab.ui.container.Panel
        GenerateButton               matlab.ui.control.Button
        IncidentBeamDropDownLabel    matlab.ui.control.Label
        IncidentBeamDropDown         matlab.ui.control.DropDown
        TmatrixDropDownLabel         matlab.ui.control.Label
        TmatrixDropDown              matlab.ui.control.DropDown
        ScatteredBeamEditFieldLabel  matlab.ui.control.Label
        ScatteredBeamEditField       matlab.ui.control.EditField
        PositionEditFieldLabel       matlab.ui.control.Label
        PositionEditField            matlab.ui.control.EditField
        RotationEditFieldLabel       matlab.ui.control.Label
        RotationEditField            matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
    end


    methods (Access = private)
    
        function RefreshDropDownLists(app, clear_value)
            
            if nargin < 2
                clear_value = false;
            end
            
            % Refresh the drop down menus
            ott.ui.support.populateVariableList(app.IncidentBeamDropDown, 'ott.Bsc');
            ott.ui.support.populateVariableList(app.TmatrixDropDown, 'ott.Tmatrix');
            
            if clear_value
                app.IncidentBeamDropDown.Value = '';
                app.TmatrixDropDown.Value = '';
            end
            
        end
    
        function ResetToDefaults(app)
            
            % Populate beam and T-matrix drop down lists
            app.RefreshDropDownLists(true);
            
            % Reset other widgets
            app.ScatteredBeamEditField.Value = 'ScatteredBeam';
            app.PositionEditField.Value = '[0;0;0]';
            app.RotationEditField.Value = 'eye(3)';
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate beam and T-matrix drop down lists
            app.ResetToDefaults();
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Get beam
            if isempty(app.IncidentBeamDropDown.Value)
                error('Must supply incident beam');
            end
            beam = evalin('base', app.IncidentBeamDropDown.Value);
            
            % Get T-matrix
            if isempty(app.TmatrixDropDown.Value)
                error('Must supply T-matrix');
            end
            Tmatrix = evalin('base', app.TmatrixDropDown.Value);
            
            % Check we have an output variable name
            if ~isvarname(app.ScatteredBeamEditField.Value)
                error('Must supply valid variable name for scattered beam');
            end
            
            % Get position and rotation
            position = evalin('base', app.PositionEditField.Value);
            rotation = evalin('base', app.RotationEditField.Value);
            
            % Convert rotation to matrix
            if numel(rotation) == 2
                rotation = ott.utils.rotz(rotation(2))*ott.utils.roty(rotation(1));
            else
                assert(all(size(rotation) == [3, 3]), 'Rotation must be 3x3 matrix');
            end
            
            assert(numel(position) == 3, 'Position must be a 3 element vector');
            
            % Scatter the beam
            sbeam = beam.scatter(Tmatrix, 'position', position(:), 'rotation', rotation);
            
            % Store the result
            assignin('base', app.ScatteredBeamEditField.Value, sbeam);
            
            
        end

        % Menu selected function: ResettoDefaultsMenu
        function ResettoDefaultsMenuSelected(app, event)
            app.ResetToDefaults();
        end

        % Menu selected function: RefreshDropdownsMenu
        function RefreshDropdownsMenuSelected(app, event)
            app.RefreshDropDownLists();
        end

        % Menu selected function: GenerateCodeMenu
        function GenerateCodeMenuSelected(app, event)
            
            assert(~isempty(app.IncidentBeamDropDown.Value), 'Incident beam must be specified');
            assert(~isempty(app.TmatrixDropDown.Value), 'T-matrix must be specified');
            
            mcode = {};
            
            % Document header
            mcode{end+1} = '% Calculate the beam scattered by a T-matrix';
            mcode{end+1} = '% Automatically generated using the Optical Tweezers Toolbox';
            mcode{end+1} = '%';
            mcode{end+1} = '% Required variables (may be others):';
            mcode{end+1} = ['%    ', app.IncidentBeamDropDown.Value];
            mcode{end+1} = ['%    ', app.TmatrixDropDown.Value];
            mcode{end+1} = '';
            
            % Setup variables
            mcode{end+1} = '% Specify position and rotation (should be 3xN and 3x3N matrices)';
            mcode{end+1} = ['position = ', app.PositionEditField.Value, ';'];
            mcode{end+1} = ['rotation = ', app.RotationEditField.Value, ';'];
            mcode{end+1} = '';
            
            % Generate scattered beam
            mcode{end+1} = '% Calculate the scattered beam';
            mcode{end+1} = ['sbeam = ', app.IncidentBeamDropDown.Value, ...
                '.scatter(', app.TmatrixDropDown.Value, ...
                ', ''position'', position, ''rotation'', rotation);'];
            
            % Convert to char array, add line endings
            mcode_str = [];
            for ii = 1:length(mcode)
                mcode_str = [mcode_str, mcode{ii}, newline]; %#ok<AGROW>
            end
            
            % Create new document
            editorDoc = matlab.desktop.editor.newDocument(mcode_str); 
            editorDoc.smartIndentContents();
            editorDoc.goToLine(1);
        end

        % Menu selected function: ExittoLauncherMenu
        function ExittoLauncherMenuSelected(app, event)
            ott.ui.support.ShowUiLauncher();
            delete(app);
        end

        % Menu selected function: GitHubRepoMenu
        function GitHubRepoMenuSelected(app, event)
            web('https://github.com/ilent2/ott', '-browser');
        end

        % Menu selected function: OnlineManualMenu
        function OnlineManualMenuSelected(app, event)
            web('https://ott.readthedocs.io/en/latest/', '-browser');
        end

        % Menu selected function: ReportaBugMenu
        function ReportaBugMenuSelected(app, event)
            web('https://github.com/ilent2/ott/issues', '-browser');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScatterBeamUIFigure = uifigure;
            app.ScatterBeamUIFigure.Name = 'Scatter Beam';
            app.FileMenu = uimenu(app.ScatterBeamUIFigure);
            app.FileMenu.Text = 'File';
            app.ResettoDefaultsMenu = uimenu(app.FileMenu);
            app.ResettoDefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultsMenuSelected, true);
            app.ResettoDefaultsMenu.Text = 'Reset to Defaults';
            app.RefreshDropdownsMenu = uimenu(app.FileMenu);
            app.RefreshDropdownsMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshDropdownsMenuSelected, true);
            app.RefreshDropdownsMenu.Text = 'Refresh Dropdowns';
            app.GenerateCodeMenu = uimenu(app.FileMenu);
            app.GenerateCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCodeMenuSelected, true);
            app.GenerateCodeMenu.Separator = 'on';
            app.GenerateCodeMenu.Text = 'Generate Code...';
            app.ExittoLauncherMenu = uimenu(app.FileMenu);
            app.ExittoLauncherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExittoLauncherMenuSelected, true);
            app.ExittoLauncherMenu.Separator = 'on';
            app.ExittoLauncherMenu.Text = 'Exit to Launcher';
            app.HelpMenu = uimenu(app.ScatterBeamUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GitHubRepoMenu = uimenu(app.HelpMenu);
            app.GitHubRepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubRepoMenuSelected, true);
            app.GitHubRepoMenu.Text = 'GitHub Repo';
            app.OnlineManualMenu = uimenu(app.HelpMenu);
            app.OnlineManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineManualMenuSelected, true);
            app.OnlineManualMenu.Text = 'Online Manual';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportaBugMenuSelected, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.Panel = uipanel(app.ScatterBeamUIFigure);
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.IncidentBeamDropDownLabel = uilabel(app.Panel);
            app.IncidentBeamDropDownLabel.Text = 'Incident Beam';
            app.IncidentBeamDropDown = uidropdown(app.Panel);
            app.IncidentBeamDropDown.Items = {};
            app.IncidentBeamDropDown.Editable = 'on';
            app.IncidentBeamDropDown.Value = {};
            app.TmatrixDropDownLabel = uilabel(app.Panel);
            app.TmatrixDropDownLabel.Text = 'T-matrix';
            app.TmatrixDropDown = uidropdown(app.Panel);
            app.TmatrixDropDown.Items = {};
            app.TmatrixDropDown.Editable = 'on';
            app.TmatrixDropDown.Value = {};
            app.ScatteredBeamEditFieldLabel = uilabel(app.Panel);
            app.ScatteredBeamEditFieldLabel.Text = 'Scattered Beam';
            app.ScatteredBeamEditField = uieditfield(app.Panel, 'text');
            app.ScatteredBeamEditField.Value = 'ScatteredBeam';
            app.PositionEditFieldLabel = uilabel(app.Panel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.Panel, 'text');
            app.PositionEditField.Value = '[0, 0, 0]';
            app.RotationEditFieldLabel = uilabel(app.Panel);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.Panel, 'text');
            app.RotationEditField.Value = 'eye(3)';
            app.TextArea = uitextarea(app.ScatterBeamUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Calculate how an incident beam scattered from a T-matrix.  Create a new scattered beam BSC object.'; ''; 'Position specifies the x, y, z, position of the T-matrix in the beam.'; ''; 'Rotation must be either a 3x3 rotation matrix or a 2 element vector with the rotation of the T-matrix from the +z axis and the rotation from the +x towards the +y axis. '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scatter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScatterBeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScatterBeamUIFigure)
        end
    end
end