classdef Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpticalTweezersToolboxLauncherUIFigure  matlab.ui.Figure
        TextArea            matlab.ui.control.TextArea
        Panel               matlab.ui.container.Panel
        LaunchButton        matlab.ui.control.Button
        TitleLabel          matlab.ui.control.Label
        ItemTextArea        matlab.ui.control.TextArea
        CategoryPanel       matlab.ui.container.Panel
        CategoryListBox     matlab.ui.control.ListBox
        ApplicationPanel    matlab.ui.container.Panel
        ApplicationListBox  matlab.ui.control.ListBox
    end


    methods (Access = private)
    
        function data = BscData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'LG beam'
                    data.Name = 'LG beam';
                    data.Description = ['Generate beam shape coefficients for a LG beam (includes Gaussian beams).'];
                    data.AppName = 'ott.ui.bsc.LgBeam';
                case 'Far-field beam'
                    data.Name = 'Far-field beam';
                    data.Description = ['Generate BSC from a far-field image of the beam.'];
                    data.AppName = 'ott.ui.bsc.FarfieldBeam';
                case 'View near-field'
                    data.Name = 'View near-field';
                    data.Description = ['Create a visualisation of the beam near-field'];
                    data.AppName = 'ott.ui.bsc.ViewNearfield';
                case 'View far-field'
                    data.Name = 'View far-field';
                    data.Description = ['Create a visualisation of the beam far-field.'];
                    data.AppName = 'ott.ui.bsc.ViewFarfield';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = TmatrixData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'STL/OBJ loader'
                    data.Name = 'STL/OBJ loader';
                    data.Description = ['Load a STL or Wavefront OBJ mesh as a T-matrix.'];
                    data.AppName = 'ott.ui.tmatrix.StlObjLoader';
                case 'Geometric shape'
                    data.Name = 'Geometric shape';
                    data.Description = ['Generate a T-matrix for a basic geometric shape.'];
                    data.AppName = 'ott.ui.tmatrix.GeometricShape';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = ToolsData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'Simple Dynamics'
                    data.Name = 'Simple Dynamics';
                    data.Description = ['Simulate the dynamics of a particle moving in a beam. '...
                        'Allows fixed time step simulations, simuations with drag or fixed displacement simulation.'];
                    data.AppName = 'ott.ui.tools.SimpleDynamics';
                case 'Force Profile'
                    data.Name = 'Force Profile';
                    data.Description = ['Generate force/torque position/rotation curves for a particle in a beam.'];
                    data.AppName = 'ott.ui.tools.ForceProfile';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialize the second list box
            app.CategoryListBox.Value = 'BSC';
            app.CategoryListBoxValueChanged([]);
        end

        % Value changed function: CategoryListBox
        function CategoryListBoxValueChanged(app, event)
            
            % Seutp the contents of the second list box
            switch app.CategoryListBox.Value
                case 'BSC'
                    app.ApplicationListBox.Items = {'LG beam', 'Far-field beam', 'View near-field', ...
                        'View far-field'};
                    app.ApplicationListBox.ItemsData = {'LG beam', 'Far-field beam', 'View near-field', ...
                        'View far-field'};
                case 'T-matrix'
                    app.ApplicationListBox.Items = {'STL/OBJ loader', 'Geometric shape'};
                    app.ApplicationListBox.ItemsData = {'STL/OBJ loader', 'Geometric shape'};
                case 'Tools'
                    app.ApplicationListBox.Items = {'Simple Dynamics', 'Force Profile'};
                    app.ApplicationListBox.ItemsData = {'Simple Dynamics', 'Force Profile'};
                otherwise
                    error('Internal error: invalid ListBox item name');
            end
            
            % Set the value of the second list box
            app.ApplicationListBox.Value = app.ApplicationListBox.ItemsData{1};
            app.ApplicationListBoxValueChanged(event);
            
        end

        % Value changed function: ApplicationListBox
        function ApplicationListBoxValueChanged(app, event)
            
            % Get the data for the application
            switch app.CategoryListBox.Value
                case 'BSC'
                    data = app.BscData();
                case 'T-matrix'
                    data = app.TmatrixData();
                case 'Tools'
                    data = app.ToolsData();
                otherwise
                    error('Internal error: invalid ListBox item name');
            end
            
            app.TitleLabel.Text = data.Name;
            app.ItemTextArea.Value = data.Description;
            app.LaunchButton.UserData = data.AppName;
            
            % If the user double clicks, fire the launch event
            % Hmm, doesn't seem to work
%             if strcmpi(get(app.OpticalTweezersToolboxLauncherUIFigure,'SelectionType'),'open')
%                 app.LaunchButtonPushed(event);
%             end
                
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            
            % Launch the user interface
            if ~isempty(app.LaunchButton.UserData)
                evalin('base', app.LaunchButton.UserData);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpticalTweezersToolboxLauncherUIFigure = uifigure;
            app.OpticalTweezersToolboxLauncherUIFigure.Name = 'Optical Tweezers Toolbox Launcher';
            app.TextArea = uitextarea(app.OpticalTweezersToolboxLauncherUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'OTT - Optical Tweezers Toolbox'; ''; 'This is the launcher for the graphical user interface.  Graphical user interfaces have been provided for some of the functionality in the toolbox.  Interfaces are split into three categories: BSC contains interfaces for generating and working with beams; T-matrix contains interfaces for working with T-matrices and Tools provides interfaces that work with both beams and particles (such as force calculation and dynamics simulations).'; ''; 'This interface and most of the related code, except where otherwise noted, is released under the Creative Commons Attribution-NonCommercial 4.0 International License.'; ''; 'If you find this package useful in academic work, please consider citing it:'; ''; 'I. C. D. Lenton, A. B. Stilgoe, T. A. Nieminen, H. Rubinsztein-Dunlop, "An object orientated optical tweezers toolbox", [Journal to be decided](link to the article)'};
            app.Panel = uipanel(app.OpticalTweezersToolboxLauncherUIFigure);
            app.LaunchButton = uibutton(app.Panel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.TitleLabel = uilabel(app.Panel);
            app.TitleLabel.Text = 'Title';
            app.ItemTextArea = uitextarea(app.Panel);
            app.CategoryPanel = uipanel(app.OpticalTweezersToolboxLauncherUIFigure);
            app.CategoryPanel.Title = 'Category';
            app.CategoryListBox = uilistbox(app.CategoryPanel);
            app.CategoryListBox.Items = {'BSC', 'T-matrix', 'Tools'};
            app.CategoryListBox.ItemsData = {'BSC', 'T-matrix', 'Tools'};
            app.CategoryListBox.ValueChangedFcn = createCallbackFcn(app, @CategoryListBoxValueChanged, true);
            app.CategoryListBox.Value = 'BSC';
            app.ApplicationPanel = uipanel(app.OpticalTweezersToolboxLauncherUIFigure);
            app.ApplicationPanel.Title = 'Application';
            app.ApplicationListBox = uilistbox(app.ApplicationPanel);
            app.ApplicationListBox.Items = {};
            app.ApplicationListBox.ValueChangedFcn = createCallbackFcn(app, @ApplicationListBoxValueChanged, true);
            app.ApplicationListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Launcher

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpticalTweezersToolboxLauncherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpticalTweezersToolboxLauncherUIFigure)
        end
    end
end