classdef APP_DATA_VIEWER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        TabGroup            matlab.ui.container.TabGroup
        SliceTab            matlab.ui.container.Tab
        XYSliderLabel       matlab.ui.control.Label
        XYSlider            matlab.ui.control.Slider
        XYdigital           matlab.ui.control.NumericEditField
        XZLabel             matlab.ui.control.Label
        XZSlider            matlab.ui.control.Slider
        YZLabel             matlab.ui.control.Label
        YZSlider            matlab.ui.control.Slider
        XZdigital           matlab.ui.control.NumericEditField
        YZdigital           matlab.ui.control.NumericEditField
        SurfaceTab          matlab.ui.container.Tab
        DataDropDownLabel   matlab.ui.control.Label
        DataDropDown        matlab.ui.control.DropDown
        AzSliderLabel       matlab.ui.control.Label
        AzSlider            matlab.ui.control.Slider
        ElSliderLabel       matlab.ui.control.Label
        ElSlider            matlab.ui.control.Slider
        ShowinfigureButton  matlab.ui.control.Button
        EqualaxisCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        data % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PositionData, ForceData)
            
            x=PositionData{1};y=PositionData{2};z=PositionData{3};
            v=ForceData;
            %             [x,y,z,v] = flow;
            % Determine the range of the volume by finding the minimum and maximum of the coordinate data.
            app.data.x=x;
            app.data.y=y;
            app.data.z=z;
            app.data.v=v;
            
            xmin = min(x(:));
            ymin = min(y(:));
            zmin = min(z(:));
            
            xmax = max(x(:));
            ymax = max(y(:));
            zmax = max(z(:));
            app.UIAxes.Colormap=jet;
            app.UIAxes.GridAlpha=0;
            slice(app.UIAxes,x,y,z,v,0,0,0);
            
            app.XYSlider.Limits=[zmin zmax];
            app.XZSlider.Limits=[ymin ymax];
            app.YZSlider.Limits=[xmin xmax];
            
            app.AzSlider.Limits=[-180 180];
            app.ElSlider.Limits=[-90 90];
            
            app.AzSlider.Value=-35;
            app.ElSlider.Value=30;
            %app.DataDropDown.Items=[{}];
        end

        % Value changed function: XYSlider
        function XYSliderValueChanged(app, event)
            value = app.XYSlider.Value;
            %             slice(app.UIAxes,app.data.x,app.data.y,app.data.z,app.data.v,value,0,0);
        end

        % Value changing function: XYSlider
        function XYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.XYdigital.Value=changingValue;
            slice(app.UIAxes,app.data.x,app.data.y,app.data.z,app.data.v,app.YZSlider.Value,app.XZSlider.Value,changingValue);
            app.UIAxes.View=[app.AzSlider.Value,app.ElSlider.Value];
            if app.EqualaxisCheckBox.Value
                axis(app.UIAxes,'equal')
            else
                axis(app.UIAxes,'normal')
            end
        end

        % Value changing function: XZSlider
        function XZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.XZdigital.Value=changingValue;
            slice(app.UIAxes,app.data.x,app.data.y,app.data.z,app.data.v,app.YZSlider.Value,changingValue,app.XYdigital.Value);
            app.UIAxes.View=[app.AzSlider.Value,app.ElSlider.Value];
            if app.EqualaxisCheckBox.Value
                axis(app.UIAxes,'equal')
            else
                axis(app.UIAxes,'normal')
            end
        end

        % Value changing function: YZSlider
        function YZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.YZdigital.Value=changingValue;
            slice(app.UIAxes,app.data.x,app.data.y,app.data.z,app.data.v,changingValue,app.XZSlider.Value,app.XYSlider.Value);
            app.UIAxes.View=[app.AzSlider.Value,app.ElSlider.Value];
            if app.EqualaxisCheckBox.Value
                axis(app.UIAxes,'equal')
            else
                axis(app.UIAxes,'normal')
            end
        end

        % Value changed function: XYdigital
        function XYdigitalValueChanged(app, event)
            app.XYSlider.Value = app.XYdigital.Value;
            XYSliderValueChanging(app, event)
        end

        % Value changed function: XZdigital
        function XZdigitalValueChanged(app, event)
            app.XZSlider.Value = app.XZdigital.Value;
            XZSliderValueChanging(app, event)
        end

        % Value changed function: YZdigital
        function YZdigitalValueChanged(app, event)
            app.XZSlider.Value = app.XZdigital.Value;
            XZSliderValueChanging(app, event)
            
        end

        % Value changing function: AzSlider
        function AzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.View=[changingValue,app.ElSlider.Value];
        end

        % Value changing function: ElSlider
        function ElSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.View=[app.AzSlider.Value,changingValue];
        end

        % Button pushed function: ShowinfigureButton
        function ShowinfigureButtonPushed(app, event)
            figure(10), slice(gca,app.data.x,app.data.y,app.data.z,app.data.v,app.YZSlider.Value,app.XZSlider.Value,app.XYSlider.Value);
            xlabel('X');ylabel('Y');zlabel('Z');
            set(gca,'View',[app.AzSlider.Value,app.ElSlider.Value])
            if app.EqualaxisCheckBox.Value
                axis equal
            else
                axis normal
            end
        end

        % Value changed function: EqualaxisCheckBox
        function EqualaxisCheckBoxValueChanged(app, event)
            value = app.EqualaxisCheckBox.Value;
            app.data.axisequal=value;
            
            if app.EqualaxisCheckBox.Value
                axis(app.UIAxes,'equal')
            else
                axis(app.UIAxes,'normal')
            end
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SliceTab = uitab(app.TabGroup);
            app.SliceTab.Title = 'Slice';
            app.XYSliderLabel = uilabel(app.SliceTab);
            app.XYSliderLabel.Text = 'XY';
            app.XYSlider = uislider(app.SliceTab);
            app.XYSlider.ValueChangedFcn = createCallbackFcn(app, @XYSliderValueChanged, true);
            app.XYSlider.ValueChangingFcn = createCallbackFcn(app, @XYSliderValueChanging, true);
            app.XYSlider.MinorTicks = [];
            app.XYdigital = uieditfield(app.SliceTab, 'numeric');
            app.XYdigital.ValueChangedFcn = createCallbackFcn(app, @XYdigitalValueChanged, true);
            app.XZLabel = uilabel(app.SliceTab);
            app.XZLabel.Text = 'XZ';
            app.XZSlider = uislider(app.SliceTab);
            app.XZSlider.ValueChangingFcn = createCallbackFcn(app, @XZSliderValueChanging, true);
            app.XZSlider.MinorTicks = [];
            app.YZLabel = uilabel(app.SliceTab);
            app.YZLabel.Text = 'YZ';
            app.YZSlider = uislider(app.SliceTab);
            app.YZSlider.ValueChangingFcn = createCallbackFcn(app, @YZSliderValueChanging, true);
            app.YZSlider.MinorTicks = [];
            app.XZdigital = uieditfield(app.SliceTab, 'numeric');
            app.XZdigital.ValueChangedFcn = createCallbackFcn(app, @XZdigitalValueChanged, true);
            app.YZdigital = uieditfield(app.SliceTab, 'numeric');
            app.YZdigital.ValueChangedFcn = createCallbackFcn(app, @YZdigitalValueChanged, true);
            app.SurfaceTab = uitab(app.TabGroup);
            app.SurfaceTab.Title = 'Surface';
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {'Fx', 'Fy', 'Fz', 'sqrt(Fx^2+Fy^2+Fz^2)'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Fx';
            app.AzSliderLabel = uilabel(app.UIFigure);
            app.AzSliderLabel.Text = 'Az';
            app.AzSlider = uislider(app.UIFigure);
            app.AzSlider.ValueChangingFcn = createCallbackFcn(app, @AzSliderValueChanging, true);
            app.ElSliderLabel = uilabel(app.UIFigure);
            app.ElSliderLabel.Text = 'El';
            app.ElSlider = uislider(app.UIFigure);
            app.ElSlider.ValueChangingFcn = createCallbackFcn(app, @ElSliderValueChanging, true);
            app.ShowinfigureButton = uibutton(app.UIFigure, 'push');
            app.ShowinfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinfigureButtonPushed, true);
            app.ShowinfigureButton.Text = 'Show in figure';
            app.EqualaxisCheckBox = uicheckbox(app.UIFigure);
            app.EqualaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualaxisCheckBoxValueChanged, true);
            app.EqualaxisCheckBox.Text = 'Equal axis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = APP_DATA_VIEWER(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end