classdef APP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveparametersMenu         matlab.ui.container.Menu
        LoadparametersMenu         matlab.ui.container.Menu
        UIAxialAxes                matlab.ui.control.UIAxes
        UIRadialAxes               matlab.ui.control.UIAxes
        SimulationparametersPanel  matlab.ui.container.Panel
        ParticlePanel              matlab.ui.container.Panel
        nparticleLabel             matlab.ui.control.Label
        nparticle                  matlab.ui.control.EditField
        nmediumLabel_2             matlab.ui.control.Label
        nmedium                    matlab.ui.control.EditField
        RadiusLabel                matlab.ui.control.Label
        radius                     matlab.ui.control.EditField
        BeamPanel                  matlab.ui.container.Panel
        PolarizationDropDownLabel  matlab.ui.control.Label
        Polarisation               matlab.ui.control.DropDown
        BeamtypeDropDownLabel      matlab.ui.control.Label
        Beamtype                   matlab.ui.control.DropDown
        ModeLabel                  matlab.ui.control.Label
        Mode1                      matlab.ui.control.NumericEditField
        Mode2                      matlab.ui.control.NumericEditField
        NAEditFieldLabel           matlab.ui.control.Label
        NA                         matlab.ui.control.NumericEditField
        w0EditFieldLabel           matlab.ui.control.Label
        w0EditField                matlab.ui.control.NumericEditField
        NmaxLabel                  matlab.ui.control.Label
        Nmax                       matlab.ui.control.NumericEditField
        ParallelcomputingCheckBox  matlab.ui.control.CheckBox
        XrangeLabel                matlab.ui.control.Label
        xrange                     matlab.ui.control.EditField
        YrangeLabel                matlab.ui.control.Label
        yrange                     matlab.ui.control.EditField
        ZrangeLabel                matlab.ui.control.Label
        zrange                     matlab.ui.control.EditField
        ProgressGaugeLabel         matlab.ui.control.Label
        ProgressGauge              matlab.ui.control.SemicircularGauge
        StartButton                matlab.ui.control.Button
        WarningsEditFieldLabel     matlab.ui.control.Label
        WarningsEditField          matlab.ui.control.EditField
        HoldaxesButton             matlab.ui.control.StateButton
        ExporttoWorkspaceButton    matlab.ui.control.Button
        ExporttoFileButton         matlab.ui.control.Button
        sEditFieldLabel            matlab.ui.control.Label
        timeleft                   matlab.ui.control.NumericEditField
        timelefttext               matlab.ui.control.EditField
        ClearButton                matlab.ui.control.Button
        DataViewerButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Parameters % Description
        data % Description
    end
    
    methods (Access = private)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Parameters.nparticle=1.45;
            app.Parameters.nmedium=1.33;
            app.Parameters.Radius=1;
            app.Parameters.Polarisation=[1,0];
            app.Parameters.BeamType=0;
            app.Parameters.xrange=str2num(app.xrange.Value);
            app.Parameters.yrange=str2num(app.yrange.Value);
            app.Parameters.zrange=str2num(app.zrange.Value);
        end

        % Value changed function: nparticle
        function nparticleValueChanged(app, event)
            value = app.nparticle.Value;
            numval=str2num(value);
            if isempty(numval)
                app.nparticle.BackgroundColor='r';
            else
                app.nparticle.BackgroundColor='w';
                app.Parameters.nparticle=numval;
            end
            
        end

        % Value changed function: nmedium
        function nmediumValueChanged(app, event)
            value = app.nmedium.Value;
            numval=str2num(value);
            if isempty(numval)
                app.nmedium.BackgroundColor='r';
            else
                app.nmedium.BackgroundColor='w';
                app.Parameters.nmedium=numval;
            end
        end

        % Value changed function: radius
        function radiusValueChanged(app, event)
            value = app.radius.Value;
            numval=str2num(value);
            if isempty(numval)
                app.radius.BackgroundColor='r';
            else
                app.radius.BackgroundColor='w';
                app.Parameters.Radius=numval;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            wavelength=1;
            beam_angle = asin(app.NA.Value/app.Parameters.nmedium)*180/pi;
            beam_params.w0 = lg_mode_w0([app.Mode1.Value app.Mode2.Value], beam_angle );
            
            particle_params.Radius=app.Parameters.Radius;
            particle_params.nparticle=app.Parameters.nparticle;
            particle_params.nmedium=app.Parameters.nmedium;
            
            beam_params.beam_type=app.Parameters.BeamType;
            beam_params.mode=[app.Mode1.Value app.Mode2.Value];
            beam_params.k=2*pi/wavelength;
            beam_params.polarisation=app.Parameters.Polarisation;
            beam_params.beam_offset=[0,0,0];
            beam_params.truncation_angle=90;
            
            scanrange.x=app.Parameters.xrange;
            scanrange.y=app.Parameters.yrange;
            scanrange.z=app.Parameters.zrange;
            
            handles.gauge=app.ProgressGauge;
            handles.xplot=app.UIRadialAxes;
            handles.zplot=app.UIAxialAxes;
            handles.timeleft=app.timeleft;
            handles.timelefttext=app.timelefttext;
            [x,fx,y,fy,z,fz]=force_sphere(app.Nmax.Value,particle_params,beam_params,scanrange,handles);
            app.data.x=x;
            app.data.y=y;
            app.data.z=z;
            app.data.fx=fx;
            app.data.fy=fy;
            app.data.fz=fz;
            %             plot(app.UIRadialAxes,x,fx)
            %             plot(app.UIAxialAxes,z,fz)
        end

        % Value changed function: Polarisation
        function PolarisationValueChanged(app, event)
            value = app.Polarisation.Value;
            switch value
                case 'Linear X'
                    polarisation=[1,0];
                case 'Linear Y'
                    polarisation=[0,1];
                case 'Left circular'
                    polarisation=[1,-1i];
                case 'Right circular'
                    polarisation=[1,1i];
            end
            app.Parameters.Polarisation=polarisation;
        end

        % Value changed function: HoldaxesButton
        function HoldaxesButtonValueChanged(app, event)
            value = app.HoldaxesButton.Value;
            if value
                hold(app.UIAxialAxes,'on')
                hold(app.UIRadialAxes,'on')
            else
                hold(app.UIAxialAxes,'off')
                hold(app.UIRadialAxes,'off')
            end
            
        end

        % Value changed function: xrange
        function xrangeValueChanged(app, event)
            value = app.xrange.Value;
            numval=str2num(value);
            if isempty(numval)
                app.xrange.BackgroundColor='r';
            else
                app.xrange.BackgroundColor='w';
                app.Parameters.xrange=numval;
            end
        end

        % Value changed function: yrange
        function yrangeValueChanged(app, event)
            value = app.yrange.Value;
            numval=str2num(value);
            if isempty(numval)
                app.yrange.BackgroundColor='r';
            else
                app.yrange.BackgroundColor='w';
                app.Parameters.yrange=numval;
            end
        end

        % Value changed function: zrange
        function zrangeValueChanged(app, event)
            value = app.zrange.Value;
            numval=str2num(value);
            if isempty(numval)
                app.zrange.BackgroundColor='r';
            else
                app.zrange.BackgroundColor='w';
                app.Parameters.zrange=numval;
            end
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            try
                assignin('base','X',app.data.x);
                assignin('base','Y',app.data.y);
                assignin('base','Z',app.data.z);
                assignin('base','FX',app.data.fx);
                assignin('base','FY',app.data.fy);
                assignin('base','FZ',app.data.fz);
                app.WarningsEditField.Value='Export successfull';
            catch
                app.WarningsEditField.Value='No results for export';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.WarningsEditField.Value='';
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            APP_DATA_VIEWER({app.data.x,app.data.y,app.data.z},sqrt(app.data.fx.^2+app.data.fy.^2+app.data.fz.^2));
        end

        % Value changed function: NA
        function NAValueChanged(app, event)
            value = app.NA.Value;
            beam_angle = asin(value/app.Parameters.nmedium)*180/pi;
            app.w0EditField.Value=lg_mode_w0([app.Mode1.Value app.Mode2.Value], beam_angle );
            
        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
%             uisave({'h','g'},'var1');
%             [filename,pathname]=uiputfile('Params.txt');
%             
%             if ~isequal(filename,0) || ~isequal(pathname,0)
%                 fileID = fopen(fullfile(pathname,filename),'w');
%                 save(filename,variables)
%                 fprintf(fileID,'%20s %4s','radius',app.radius.Value);
%                 fprintf(fileID,'\n%20s %4s','nmedium',app.nmedium.Value);
%                 fprintf(fileID,'\n%20s %4s','Polarisation',app.Polarisation.Value);
%                 fprintf(fileID,'\n%20s %4s','Beamtype',app.Beamtype.Value);
%                 fprintf(fileID,'\n%20s %4d','Mode1',app.Mode1.Value);
%                 fprintf(fileID,'\n%20s %4d','Mode2',app.Mode2.Value);
%                 fprintf(fileID,'\n%20s %4d','NA',app.NA.Value);
%                 fprintf(fileID,'\n%20s %4s','xrange',app.xrange.Value);
%                 fprintf(fileID,'\n%20s %4s','yrange',app.yrange.Value);
%                 fprintf(fileID,'\n%20s %4s','zrange',app.zrange.Value);
%                 fprintf(fileID,'\n%20s %4d','Nmax',app.Nmax.Value);

%                 names=fieldnames(app.Parameters);
%                 for i=1:length(names)
%                     fprintf(fileID,'%20s %4g\n',names{i},getfield(app.Parameters,names{i}));
%                 end
%                 fclose(fileID);
%             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveparametersMenu = uimenu(app.FileMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.LoadparametersMenu = uimenu(app.FileMenu);
            app.LoadparametersMenu.Text = 'Load parameters';
            app.UIAxialAxes = uiaxes(app.UIFigure);
            title(app.UIAxialAxes, 'Z force')
            xlabel(app.UIAxialAxes, 'Z')
            ylabel(app.UIAxialAxes, 'Q_z')
            app.UIRadialAxes = uiaxes(app.UIFigure);
            title(app.UIRadialAxes, 'X force')
            xlabel(app.UIRadialAxes, 'X')
            ylabel(app.UIRadialAxes, 'Q_x')
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.ParticlePanel = uipanel(app.SimulationparametersPanel);
            app.ParticlePanel.Title = 'Particle';
            app.nparticleLabel = uilabel(app.ParticlePanel);
            app.nparticleLabel.Text = 'n particle';
            app.nparticle = uieditfield(app.ParticlePanel, 'text');
            app.nparticle.ValueChangedFcn = createCallbackFcn(app, @nparticleValueChanged, true);
            app.nparticle.Value = '1.45';
            app.nmediumLabel_2 = uilabel(app.ParticlePanel);
            app.nmediumLabel_2.Text = 'n medium';
            app.nmedium = uieditfield(app.ParticlePanel, 'text');
            app.nmedium.ValueChangedFcn = createCallbackFcn(app, @nmediumValueChanged, true);
            app.nmedium.Value = '1.33';
            app.RadiusLabel = uilabel(app.ParticlePanel);
            app.RadiusLabel.Text = 'Radius';
            app.radius = uieditfield(app.ParticlePanel, 'text');
            app.radius.ValueChangedFcn = createCallbackFcn(app, @radiusValueChanged, true);
            app.radius.Value = '1';
            app.BeamPanel = uipanel(app.SimulationparametersPanel);
            app.BeamPanel.Title = 'Beam';
            app.PolarizationDropDownLabel = uilabel(app.BeamPanel);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.Polarisation = uidropdown(app.BeamPanel);
            app.Polarisation.Items = {'Linear X', 'Linear Y', 'Left circular', 'Right circular'};
            app.Polarisation.ValueChangedFcn = createCallbackFcn(app, @PolarisationValueChanged, true);
            app.Polarisation.Value = 'Linear X';
            app.BeamtypeDropDownLabel = uilabel(app.BeamPanel);
            app.BeamtypeDropDownLabel.Text = 'Beam type';
            app.Beamtype = uidropdown(app.BeamPanel);
            app.Beamtype.Items = {'HG', 'LG'};
            app.Beamtype.Value = 'LG';
            app.ModeLabel = uilabel(app.BeamPanel);
            app.ModeLabel.Text = 'Mode';
            app.Mode1 = uieditfield(app.BeamPanel, 'numeric');
            app.Mode2 = uieditfield(app.BeamPanel, 'numeric');
            app.NAEditFieldLabel = uilabel(app.BeamPanel);
            app.NAEditFieldLabel.Text = 'NA';
            app.NA = uieditfield(app.BeamPanel, 'numeric');
            app.NA.LowerLimitInclusive = 'off';
            app.NA.Limits = [0 Inf];
            app.NA.ValueChangedFcn = createCallbackFcn(app, @NAValueChanged, true);
            app.NA.Value = 1;
            app.w0EditFieldLabel = uilabel(app.BeamPanel);
            app.w0EditFieldLabel.Text = 'w0';
            app.w0EditField = uieditfield(app.BeamPanel, 'numeric');
            app.w0EditField.Editable = 'off';
            app.NmaxLabel = uilabel(app.SimulationparametersPanel);
            app.NmaxLabel.Text = 'Nmax';
            app.Nmax = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.Nmax.Limits = [1 Inf];
            app.Nmax.RoundFractionalValues = 'on';
            app.Nmax.Value = 20;
            app.ParallelcomputingCheckBox = uicheckbox(app.SimulationparametersPanel);
            app.ParallelcomputingCheckBox.Text = 'Parallel computing';
            app.XrangeLabel = uilabel(app.SimulationparametersPanel);
            app.XrangeLabel.Text = 'X range';
            app.xrange = uieditfield(app.SimulationparametersPanel, 'text');
            app.xrange.ValueChangedFcn = createCallbackFcn(app, @xrangeValueChanged, true);
            app.xrange.Value = '-2.5:2.5';
            app.YrangeLabel = uilabel(app.SimulationparametersPanel);
            app.YrangeLabel.Text = 'Y range';
            app.yrange = uieditfield(app.SimulationparametersPanel, 'text');
            app.yrange.ValueChangedFcn = createCallbackFcn(app, @yrangeValueChanged, true);
            app.yrange.Value = '-2.5:2.5';
            app.ZrangeLabel = uilabel(app.SimulationparametersPanel);
            app.ZrangeLabel.Text = 'Z range';
            app.zrange = uieditfield(app.SimulationparametersPanel, 'text');
            app.zrange.ValueChangedFcn = createCallbackFcn(app, @zrangeValueChanged, true);
            app.zrange.Value = '-2.5:2.5';
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'semicircular');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.WarningsEditFieldLabel = uilabel(app.UIFigure);
            app.WarningsEditFieldLabel.Text = 'Warnings';
            app.WarningsEditField = uieditfield(app.UIFigure, 'text');
            app.WarningsEditField.Editable = 'off';
            app.HoldaxesButton = uibutton(app.UIFigure, 'state');
            app.HoldaxesButton.ValueChangedFcn = createCallbackFcn(app, @HoldaxesButtonValueChanged, true);
            app.HoldaxesButton.Text = 'Hold axes';
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.ExporttoFileButton = uibutton(app.UIFigure, 'push');
            app.ExporttoFileButton.Text = 'Export to File';
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.timeleft = uieditfield(app.UIFigure, 'numeric');
            app.timeleft.Limits = [0 Inf];
            app.timeleft.ValueDisplayFormat = '%.1f';
            app.timeleft.Editable = 'off';
            app.timelefttext = uieditfield(app.UIFigure, 'text');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DataViewerButton = uibutton(app.UIFigure, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = 'Data Viewer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = APP_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end